classdef SAM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SAMAutomaticSegmentationToolUIFigure  matlab.ui.Figure
        SAMSegmentationToolButton       matlab.ui.control.Button
        OutputFolderEditField           matlab.ui.control.EditField
        InputFolderEditField            matlab.ui.control.EditField
        SegmentationInfoPanel           matlab.ui.container.Panel
        InfoTextArea                    matlab.ui.control.TextArea
        InputParametersPanel            matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        AreaScalingFactorEditField      matlab.ui.control.NumericEditField
        AreaScalingFactorLabel          matlab.ui.control.Label
        MinimumAreaObjectSizeEditField  matlab.ui.control.NumericEditField
        MinimumAreaObjectSizeEditFieldLabel  matlab.ui.control.Label
        DiskRadiusEditField             matlab.ui.control.NumericEditField
        DiskRadiusEditFieldLabel        matlab.ui.control.Label
        ScaleFactorEditField            matlab.ui.control.NumericEditField
        ScaleFactorEditFieldLabel       matlab.ui.control.Label
        LoadModelButton                 matlab.ui.control.Button
        OutputFolderButton              matlab.ui.control.Button
        InputFolderButton               matlab.ui.control.Button
        SegmentationButton              matlab.ui.control.Button
    end


    properties (Access = private)
        inputFolder = '';       % Input Folder
        outputFolder = '';      % output Folder
        samObj = [];            % SAM Model
        imageFiles = [];        % file image list
        currentFrame = 1;       % Current Frame
        BW = [];                % Binary Mask
        minObjectSize = 100;    % Minimal object dimension
        correctionStartFrame = 1; % initial frame for correction
        correctionEndFrame = 1;  % final frame for correction
        manualMask = [];        % Manual mask created by user % Description
        modelLoaded = false; % Flag to indicate if the model is loaded
        modelLoadingDialog % Dialog for loading the model

    end

    methods (Access = private)


        function updateSegmentationInfo(app, meanArea, minObjectSize)
            infoText = {
                sprintf('Mean Segmented Area: %.2f px', meanArea),
                sprintf('Minimum Object Size: %d px', minObjectSize),

                };
            app.InfoTextArea.Value = infoText;
        end

        function startupFcn(app)
            % Set default values
            app.ScaleFactorEditField.Value = 0.3;
            app.DiskRadiusEditField.Value = 2;
            app.MinimumAreaObjectSizeEditField.Value = 100;
            app.AreaScalingFactorEditField.Value = 0.005;
            % Disable segmentation button at the start
            app.SegmentationButton.Enable = 'off';
            % Initialize added properties
            app.modelLoadingDialog = [];
            app.modelLoaded = false;
            app.InfoTextArea.Value = {'Waiting for first segmentation...'};

        end

        % Helper function for natural sorting
        function [sortedNames, index] = sort_nat(~, names)
            % Natural order sort of cell array of strings
            if isempty(names)
                sortedNames = {};
                index = [];
                return;
            end

            % Extract numbers
            num = regexp(names, '(\d+)', 'tokens');
            num = cellfun(@(x) str2double(x{1}), num, 'UniformOutput', false);
            num(cellfun(@isempty, num)) = {Inf};
            num = cell2mat(num);

            % Sort
            [~, index] = sort(num);
            sortedNames = names(index);
        end

        % Function for initial manual segmentation
        function runInitialSegmentation(app, scaleFactor, diskRadius, minAreaThreshold, areaThresholdFactor)
            % Show progress bar during initialization
            loadingDialog = uiprogressdlg(app.SAMAutomaticSegmentationToolUIFigure,...
                'Title','Preparation in progress',...
                'Message','Preparing the interface for manual segmentation...',...
                'Indeterminate','on');

            try
                % Load first frame
                firstImagePath = fullfile(app.imageFiles(1).folder, app.imageFiles(1).name);
                I = imread(firstImagePath);

                % Update progress bar message
                loadingDialog.Message = 'Loading interface for segmentation...';
                drawnow; % force the update of UI

                % Extracting embeddings (use GPU if is available)
                if canUseGPU
                    I_gpu = gpuArray(I);
                else
                    I_gpu = I;
                end

                embeddings = extractEmbeddings(app.samObj, I_gpu);

                % Close progress bar
                if isvalid(loadingDialog)
                    close(loadingDialog);
                end

                % Create a separate figure for ROI selection
                f = figure('Name', 'Selection ROI - Frame 1', 'NumberTitle', 'off',...
                    'WindowStyle', 'modal'); % Modal to prevent interactions with the main app
                hIm = imshow(I);
                app.BW = false(size(I,1), size(I,2));

                % ROI selection loop
                selectionComplete = false;
                while ~selectionComplete
                    choice = questdlg('Choose type of selection:', ...
                        'ROI Mode', ...
                        'Rectangle', 'Manual contour', 'End', 'Rectangle');

                    switch choice
                        case 'Rectangle'
                            roi = drawrectangle(gca, 'Color', 'r');
                            box = roi.Position;
                            currMask = segmentObjectsFromEmbeddings(app.samObj, embeddings, size(I), 'BoundingBox', box);
                            app.BW = app.BW | gather(currMask);
                            delete(roi);

                        case 'Manual contour'
                            roi = drawfreehand(gca, 'Color', 'b');
                            maskManual = createMask(roi);
                            if ~any(maskManual(:))
                                warning('Empty or invalid contour');
                                continue;
                            end
                            app.BW = app.BW | maskManual;
                            delete(roi);

                        case 'End'
                            selectionComplete = true;

                        otherwise
                            close(f);
                            return;
                    end

                    % Preview display with colored overlay
                    hIm.CData = labeloverlay(I, app.BW,...
                        'Colormap', [1 0 0; 0 1 0],...
                        'Transparency', 0.7);

                    % If the user has selected “Terminate,” ask for confirmation.
                    if selectionComplete
                        confirm = questdlg('Are you satisfied with the selection made?', ...
                            'Confirm selection', ...
                            'Yes, proceed', 'No, remake', 'Delete', 'Yes, proceed');

                        switch confirm
                            case 'Yes, proceed'
                                selectionComplete = true;
                            case 'No, remake'
                                app.BW = false(size(I,1), size(I,2));
                                hIm.CData = I;
                                selectionComplete = false;
                            case 'Delete'
                                close(f);
                                return;
                        end
                    end
                end

                close(f);

                % Save the initial mask
                [~, baseName, ~] = fileparts(app.imageFiles(1).name);
                maskPath = fullfile(app.outputFolder, [baseName '_mask.png']);
                imwrite(app.BW, maskPath);

                % Calculate minObjectSize automatically
                stats = regionprops(app.BW, 'Area');
                if isempty(stats)
                    app.minObjectSize = 100;
                    warning('No object found in the initial mask');
                    app.InfoTextArea.Value = {
                        'No objects found in initial mask',
                        'Using default minObjectSize: 100 pixels'
                        };
                else
                    meanArea = mean([stats.Area]);
                    app.minObjectSize = round(meanArea * scaleFactor);
                    fprintf('Automatic minimum object size: %d px\n', app.minObjectSize);
                    % Update the TextArea
                    app.updateSegmentationInfo(meanArea, app.minObjectSize);

                    fprintf('Mean segmented area = %.2f px. Auto minObjectSize threshold set to %d px.\n',...
                        meanArea, app.minObjectSize);
                end

                % Extract initial bounding boxes
                stats = regionprops(app.BW, 'BoundingBox');
                if isempty(stats)
                    uialert(app.SAMAutomaticSegmentationToolUIFigure, 'No object found to extract bounding boxes', 'Error');
                    return;
                end
                bboxes = vertcat(stats.BoundingBox);

                % Perform automatic segmentation on subsequent frames
                app.runAutomaticSegmentation(embeddings, bboxes, diskRadius, minAreaThreshold, areaThresholdFactor, true);

                % Ask the user for feedback with a more informative dialog
                userSatisfied = questdlg({...
                    'Is the automatic segmentation satisfying?',...
                    '',...
                    'Choose "No, correct" to manually edit some frames',...
                    'Choose "Cancel" to stop the process'}, ...
                    'Segmentation Feedback', ...
                    'Yes', 'No, correct', 'Cancel', 'Yes');

                switch userSatisfied
                    case 'Yes'
                        msgbox('Segmentation completed successfully!', 'Completed', 'help');
                    case 'No, correct'
                        app.runCorrectionMode(scaleFactor, diskRadius, minAreaThreshold, areaThresholdFactor);
                    case 'Cancel'
                        return;
                end

            catch ME
                % Error handling with cleanup
                if isvalid(loadingDialog)
                    close(loadingDialog);
                end
                errMsg = sprintf('Error initializing segmentation:\n%s', ME.message);
                uialert(app.SAMAutomaticSegmentationToolUIFigure, errMsg, 'Error', 'Icon', 'error');

                % Complete error log for debugging
                fprintf(2, 'Error in runInitialSegmentation:\n');
                fprintf(2, '%s\n', getReport(ME, 'extended', 'hyperlinks', 'off'));
                return;
            end
        end

        % Automatic segmentation on subsequent frames
        function runAutomaticSegmentation(app, initialEmbeddings, initialBBoxes, diskRadius, minAreaThreshold, areaThresholdFactor, isInitialRun)
            currentEmbeddings = initialEmbeddings;
            currentBBoxes = initialBBoxes;

            % Determine the range of frames to be processed
            if isInitialRun
                startFrame = 2; % For the first run, start at frame 2.
                endFrame = app.correctionEndFrame;
            else
                startFrame = app.correctionStartFrame + 1; % Starts from the frame following the selected one
                endFrame = app.correctionEndFrame;
            end

            % Check that there are frames to process
            if startFrame > endFrame
                return;
            end

            totalFrames = endFrame - startFrame + 1;
            currentFrame = 0;

            hWaitbar = waitbar(0, sprintf('Processing frame %d/%d', startFrame, endFrame), ...
                'Name', 'Automatic Segmentation', ...
                'CreateCancelBtn', 'setappdata(gcbf,''canceling'',1)');

            setappdata(hWaitbar, 'canceling', 0);

            for k = startFrame:endFrame
                % Check for Cancel button press
                if getappdata(hWaitbar, 'canceling')
                    break;
                end

                currentFrame = currentFrame + 1;

                try
                    % Update progress bar
                    progress = currentFrame / totalFrames;
                    waitbar(progress, hWaitbar, ...
                        sprintf('Processing frame %d/%d', k, endFrame));

                    % Load current frame
                    framePath = fullfile(app.imageFiles(k).folder, app.imageFiles(k).name);
                    currImg = imread(framePath);
                    imgSize = size(currImg);

                    % Extract embeddings
                    if canUseGPU
                        currImgGPU = gpuArray(currImg);
                    else
                        currImgGPU = currImg;
                    end
                    embeddings = extractEmbeddings(app.samObj, currImgGPU);

                    % initialize mask
                    totalMask = false(imgSize(1), imgSize(2));

                    % Segment using the current bounding boxes
                    for i = 1:size(currentBBoxes,1)
                        currMask = segmentObjectsFromEmbeddings(app.samObj, embeddings, imgSize, 'BoundingBox', currentBBoxes(i,:));
                        totalMask = totalMask | gather(currMask);
                    end

                    % Clean the mask
                    [L, num] = bwlabel(totalMask);
                    stats = regionprops(L, 'Area');
                    cleanMask = false(size(totalMask));

                    for i = 1:num
                        if stats(i).Area >= app.minObjectSize
                            cleanMask = cleanMask | (L == i);
                        end
                    end

                    % Morphological operations if necessary
                    [imageHeight, imageWidth, ~] = size(currImg);
                    dynamicAreaThreshold = areaThresholdFactor * imageHeight * imageWidth;

                    if mean([stats.Area]) > dynamicAreaThreshold
                        totalMask = imclose(cleanMask, strel('disk', diskRadius));
                        totalMask = imfill(totalMask, 'holes');
                    else
                        totalMask = cleanMask;
                    end

                    % Save the mask
                    [~, name, ~] = fileparts(app.imageFiles(k).name);
                    imwrite(totalMask, fullfile(app.outputFolder, [name '_mask.png']));

                    % Update embeddings and bounding boxes
                    maskedImage = currImg;
                    if size(maskedImage,3) == 3
                        maskedImage(repmat(~totalMask, [1 1 3])) = 0;
                    else
                        maskedImage(~totalMask) = 0;
                    end

                    if canUseGPU
                        maskedImageGPU = gpuArray(maskedImage);
                    else
                        maskedImageGPU = maskedImage;
                    end

                    currentEmbeddings = extractEmbeddings(app.samObj, maskedImageGPU);

                    stats = regionprops(totalMask, 'BoundingBox');
                    if ~isempty(stats)
                        newBBoxes = vertcat(stats.BoundingBox);

                        % Filter bounding boxes that are too small
                        validBoxes = [];
                        for j = 1:size(newBBoxes,1)
                            if newBBoxes(j,3) * newBBoxes(j,4) > minAreaThreshold
                                validBoxes = [validBoxes; newBBoxes(j,:)];
                            end
                        end

                        if ~isempty(validBoxes)
                            currentBBoxes = validBoxes;
                        end
                    end

                catch ME
                    warning('Error in frame %d: %s', k, ME.message);
                end
            end

            delete(hWaitbar); 
        end

        % Correction modalities
        function runCorrectionMode(app, scaleFactor, diskRadius, minAreaThreshold, areaThresholdFactor)
            answer = inputdlg({'Initial frame to correct:', 'Final frame to correct:'}, ...
                'Segmentation Correction', [1 50], ...
                {num2str(min(2, length(app.imageFiles))), num2str(length(app.imageFiles))});

            if isempty(answer)
                return;
            end

            app.correctionStartFrame = round(str2double(answer{1}));
            app.correctionEndFrame = round(str2double(answer{2}));

            if isnan(app.correctionStartFrame) || isnan(app.correctionEndFrame) || ...
                    app.correctionStartFrame < 1 || app.correctionEndFrame > length(app.imageFiles) || app.correctionStartFrame > app.correctionEndFrame
                uialert(app.SAMAutomaticSegmentationToolUIFigure, 'Invalid frame range', 'Error');
                return;
            end

            % Correction mode selection
            modeChoice = questdlg('Select the type of correction:', ...
                'Correction Mode', ...
                'Overwrite all', ...
                'Add to existing masks', ...
                'Overwrite all');

            % Load the starting frame for correction
            framePath = fullfile(app.imageFiles(app.correctionStartFrame).folder, app.imageFiles(app.correctionStartFrame).name);
            I = imread(framePath);

            % Extract embeddings
            if canUseGPU
                I_gpu = gpuArray(I);
            else
                I_gpu = I;
            end
            embeddings = extractEmbeddings(app.samObj, I_gpu);

            % Create separate figure for correction
            f = figure('Name', sprintf('Correction - Frame %d', app.correctionStartFrame), 'NumberTitle', 'off');
            hIm = imshow(I);
            app.manualMask = false(size(I,1), size(I,2));

            % If in “add” mode, load the existing mask
            if strcmp(modeChoice, 'Add to existing masks')
                [~, name, ~] = fileparts(app.imageFiles(app.correctionStartFrame).name);
                maskPath = fullfile(app.outputFolder, [name '_mask.png']);
                if isfile(maskPath)
                    existingMask = imread(maskPath);
                    if ~islogical(existingMask)
                        existingMask = existingMask > 0;
                    end
                    app.manualMask = existingMask;
                    hIm.CData = labeloverlay(I, app.manualMask, 'Colormap', 'autumn');
                end
            end

            % ROI selection loop for correction
            selectionComplete = false;
            while ~selectionComplete
                choice = questdlg('Choose the type of selection:', ...
                    'ROI Mode', ...
                    'Rectangle', 'Manual contour', 'End', 'Rectangle');

                switch choice
                    case 'Rectangle'
                        roi = drawrectangle(gca);
                        box = roi.Position;
                        currMask = segmentObjectsFromEmbeddings(app.samObj, embeddings, size(I), 'BoundingBox', box);
                        app.manualMask = app.manualMask | gather(currMask);
                        delete(roi);

                    case 'Manual contour'
                        roi = drawfreehand(gca);
                        maskManual = createMask(roi);
                        if ~any(maskManual(:))
                            warning('Empty or invalid contour');
                            continue;
                        end
                        app.manualMask = app.manualMask | maskManual;
                        delete(roi);

                    case 'End'
                        selectionComplete = true;

                    otherwise
                        close(f);
                        return;
                end

                % Show preview
                hIm.CData = labeloverlay(I, app.manualMask, 'Colormap', 'autumn');

                % If the user has selected “Terminate,” ask for confirmation.
                if selectionComplete
                    confirm = questdlg('Are you satisfied with the selection made?', ...
                        'Confirm selection', ...
                        'Yes, proceed', 'No, remake', 'Cancel', 'Yes, proceed');

                    switch confirm
                        case 'Yes, proceed'
                            % Proceed with segmentation
                            selectionComplete = true;
                        case 'No, remake'
                            % Reset and continue
                            app.manualMask = false(size(I,1), size(I,2));
                            hIm.CData = I;
                            selectionComplete = false;
                        case 'Cancel'
                            close(f);
                            return;
                    end
                end
            end

            close(f);

            % Save the manual mask (NOT overwritten by automatic segmentation)
            [~, name, ~] = fileparts(app.imageFiles(app.correctionStartFrame).name);
            imwrite(app.manualMask, fullfile(app.outputFolder, [name '_mask.png']));

            % Calculate minObjectSize on the manual mask
            stats = regionprops(app.manualMask, 'Area');
            if isempty(stats)
                app.minObjectSize = 100;
                app.InfoTextArea.Value = {
                    'No objects found after correction',
                    'Using default minObjectSize: 100 pixels'
                    };
            else
                meanArea = mean([stats.Area]);
                app.minObjectSize = round(meanArea * scaleFactor);
                app.updateSegmentationInfo(meanArea, app.minObjectSize);
            end

            % Extract bounding boxes from the manual mask
            stats = regionprops(app.manualMask, 'BoundingBox');
            if isempty(stats)
                uialert(app.SAMAutomaticSegmentationToolUIFigure, 'No object found after the correction', 'Error');
                return;
            end
            bboxes = vertcat(stats.BoundingBox);

            % Perform automatic segmentation ONLY on subsequent frames
            app.runAutomaticSegmentation(embeddings, bboxes, diskRadius, minAreaThreshold, areaThresholdFactor, false);

            % Ask the user for feedback again
            userSatisfied = questdlg('Is the correction satysfing?', ...
                'Correction feedback', 'Yes', 'No, correct again', 'Cancel', 'Yes');

            switch userSatisfied
                case 'Yes'
                    msgbox('Correction completed succesfully!', 'Completed');
                case 'No, correct again'
                    app.runCorrectionMode(scaleFactor, diskRadius, minAreaThreshold, areaThresholdFactor);
                case 'Cancel'
                    return;
            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InputFolderButton
        function InputFolderButtonPushed(app, event)
            app.inputFolder = uigetdir(pwd, 'Select frame folder');
            if app.inputFolder == 0
                uialert(app.SAMAutomaticSegmentationToolUIFigure, 'No folder selected', 'Warning');
                return;
            end

            app.InputFolderEditField.Value = app.inputFolder;

            % Load the list of images
            app.imageFiles = dir(fullfile(app.inputFolder, '*.png'));
            if isempty(app.imageFiles)
                uialert(app.SAMAutomaticSegmentationToolUIFigure, 'No PNG files found in the folder', 'Error');
                return;
            end

            % Sort files numerically
            [~, idx] = app.sort_nat({app.imageFiles.name});
            app.imageFiles = app.imageFiles(idx);

            uialert(app.SAMAutomaticSegmentationToolUIFigure, sprintf('Loaded %d frame', length(app.imageFiles)), 'Success');
        end

        % Button pushed function: OutputFolderButton
        function OutputFolderButtonPushed(app, event)
            app.outputFolder = uigetdir(pwd, 'Select the folder to save the masks');
            if app.outputFolder == 0
                uialert(app.SAMAutomaticSegmentationToolUIFigure, 'No folder selected', 'Warning');
            else
                app.OutputFolderEditField.Value = app.outputFolder;
                uialert(app.SAMAutomaticSegmentationToolUIFigure, sprintf('Output folder set: %s', app.outputFolder), 'Success');
            end
        end

        % Button pushed function: LoadModelButton
        function LoadModelButtonPushed(app, event)
            % Create and display the load dialog box
            app.modelLoadingDialog = uiprogressdlg(app.SAMAutomaticSegmentationToolUIFigure,...
                'Title','Processing in progress',...
                'Message','Loading SAM Model...',...
                'Indeterminate','on');

            try
                % Disable the button during loading
                app.LoadModelButton.Enable = 'off';
                drawnow; % Forza l'aggiornamento dell'interfaccia

                % Load the template (this may take some time)
                app.samObj = segmentAnythingModel("sam-base");
                app.modelLoaded = true;

                % Close the dialog box
                if isvalid(app.modelLoadingDialog)
                    close(app.modelLoadingDialog);
                end

                % Enable segmentation button
                app.SegmentationButton.Enable = 'on';

                % Display success message
                uialert(app.SAMAutomaticSegmentationToolUIFigure, 'SAM model loaded successfully', 'Success');

            catch ME
                % In case of error, closes the dialog and displays a message.
                if isvalid(app.modelLoadingDialog)
                    close(app.modelLoadingDialog);
                end

                % Rehabilitate the button
                app.LoadModelButton.Enable = 'on';
                app.modelLoaded = false;

                uialert(app.SAMAutomaticSegmentationToolUIFigure, sprintf('Error loading the model: %s', ME.message), 'Error');
            end
        end

        % Button pushed function: SegmentationButton
        function SegmentationButtonPushed(app, event)
            % Check prerequisites
            if isempty(app.samObj)
                uialert(app.SAMAutomaticSegmentationToolUIFigure, 'First, load SAM model', 'Error');
                return;
            end

            if isempty(app.inputFolder) || isempty(app.outputFolder)
                uialert(app.SAMAutomaticSegmentationToolUIFigure, 'Select both input and output folders', 'Error');
                return;
            end

            % Get parameters from the interface
            scaleFactor = app.ScaleFactorEditField.Value;
            diskRadius = app.DiskRadiusEditField.Value;
            minAreaThreshold = app.MinimumAreaObjectSizeEditField.Value;
            areaThresholdFactor = app.AreaScalingFactorEditField.Value;

            % Set the frames for correction (all initially)
            app.correctionStartFrame = 1;
            app.correctionEndFrame = length(app.imageFiles);

            % Segmentation begins
            app.runInitialSegmentation(scaleFactor, diskRadius, minAreaThreshold, areaThresholdFactor);
        end

        % Callback function
        function SAMSegmentationToolButtonPushed(app, event)
            infoText = {
                ' AUTOMATIC SEGMENTATION USING THE SEGMENT ANYTHING MODEL';
                '';
                ' ==============================================================================';
                ' AUTHOR: Sarneri Michele';
                '';
                ' DATE  : [06/06/2025]';
                '';
                ' FUNCTION: AutomaticSegmentation';
                '';
                ' PURPOSE:';
                '';
                ' This MATLAB function performs automatic segmentation on a sequence of video';
                ' frames or images, using image processing techniques to identify and isolate';
                ' regions of interest with just an inital manual intervention. The process is designed';
                ' for applications in medical , industrial, or scientific domains where extracting';
                ' relevant regions from raw visual data is essential. It can also be used in the field ';
                ' of video surveillance to identify subjects of interest taken as a reference.';
                '';
                ' FUNCTIONAL OVERVIEW:';
                '';
                ' 1. Accepts an array or cell array of frames/images as input.';
                ' 2. Applies a series of preprocessing steps (grayscale conversion, filtering,';
                '    normalization).';
                ' 3. Uses segmentation algorithms (e.g., adaptive thresholding, edge detection,';
                '    clustering, or morphological operations) to isolate target regions.';
                ' 4. Post-processes the segmented output to enhance accuracy by removing noise';
                '    and eliminating irrelevant small regions.';
                ' 5. Returns the segmented masks and/or overlays the results on the original frames.';
                '';
                ' INPUT PARAMETERS:';
                '';
                ' - inputFolder with frameData: Array or cell array containing the video frames/images to be segmented.';
                ' - parameters: Optional structure specifying segmentation settings (e.g., thresholds, filters, minimum region size, etc.).';
                ' - outputFolder: (Optional) Path to save the segmented images.';
                '';
                ' OUTPUT:';
                ' - Binary masks or segmented images, returned as arrays or saved to disk.';
                ' - (Optional) Interactive display of the segmentation results.';
                '';
                ' FEATURES:';
                '';
                ' - Fully automatic segmentation, with a small group of inputs by the user.';
                ' - Configurable for different domains through customizable parameters.';
                ' - Supports batch processing of long image or video sequences.';
                ' - Modular codebase, easy to extend with additional segmentation techniques.';
                '';
                ' REQUIREMENTS:';
                '';
                ' - MATLAB with the Image Processing Toolbox.';
                ' - MATLAB with the Deep Learning Toolbox.';
                ' - MATLAB with the Mapping Toolbox.';
                ' - MATLAB with the Medical Imaging Toolbox.';
                ' - MATLAB with the Computer Vision Toolbox.';
                ' - MATLAB with the Image Processing Toolbox Model for Segment Anything Model';
                ' - Images must be preloaded or acquired from file/video.';
                '';
                ' USAGE EXAMPLE:';
                '   AutomaticSegmentation(frameData, struct(''threshold'', 0.3, ''minArea'', 50), ''C:\segmented\'');';
                '';
                ' ==============================================================================';
                };

            % Create a dialog box with text
            fig = uifigure('Name', 'SAM Segmentation Tool Info', 'Position', [100 100 800 600]);
            txt = uitextarea(fig, 'Value', infoText, 'Position', [20 20 760 560], ...
                'FontName', 'FixedWidth', 'Editable', 'off');
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            % Text of explanations
            helpText = {
                '1. Scale Factor:';
                '';
                'Defines the radius of the structuring element used for morphological operations on the binary image. A higher scale factor results in a larger disk-shaped structuring element, which has a stronger effect on smoothing the image, removing small objects, or merging nearby regions.';
                '';
                'Higher value: more aggressive filtering, may removes small or fine details;'
                '';
                'Lower value: preserves details, less aggressive morphological effect.';
                '';
                '2. Disk Radius:';
                '';
                'Specifies the radius (in pixels) of the circular structuring element used in morphological operations (e.g., noise removal or gap closing).';
                '';
                '3. Min Area Object Size:';
                '';
                'Defines the minimum area (in pixels) that an object must have to be considered valid. Smaller objects are ignored as noise or artifacts.';
                '';
                '4. Area Scaling Factor:';
                '';
                'This is a multiplier applied to the average (or median) area of detected objects in the image. It is used to dynamically set a threshold for filtering out objects that are significantly larger or smaller than typical ones.';
                '';
                'example: If the average object area is 100 px² and the factor is 1.5, the threshold becomes 150 px² (Objects outside this range can be excluded to improve accuracy).'
                '';
                'Higher values: stricter filtering, allowing only objects close to average size.';
                '';
                'Lower values: more tolerant to variations in object size';
                };

            % Create the pop-up with uialert (integrated with App Designer)
            uialert(app.SAMAutomaticSegmentationToolUIFigure, helpText, 'Parameters Help', ...
                'Icon', 'info', ...
                'Interpreter', 'none');
        end

        % Button pushed function: SAMSegmentationToolButton
        function SAMSegmentationToolButtonPushed2(app, event)
            infoText = {
                ' AUTOMATIC SEGMENTATION USING THE SEGMENT ANYTHING MODEL';
                '';
                ' ==============================================================================';
                ' AUTHOR: Sarneri Michele';
                '';
                ' DATE  : [20/05/2026]';
                '';
                ' FUNCTION: AutomaticSegmentation';
                '';
                ' PURPOSE:';
                '';
                ' This MATLAB function performs automatic segmentation on a sequence of video';
                ' frames or images, using image processing techniques to identify and isolate';
                ' regions of interest with just an inital manual intervention. The process is designed';
                ' for applications in medical , industrial, or scientific domains where extracting';
                ' relevant regions from raw visual data is essential. It can also be used in the field ';
                ' of video surveillance to identify subjects of interest taken as a reference.';
                '';
                ' FUNCTIONAL OVERVIEW:';
                '';
                ' 1. Accepts an array or cell array of frames/images as input.';
                ' 2. Applies a series of preprocessing steps (grayscale conversion, filtering,';
                '    normalization).';
                ' 3. Uses segmentation algorithms (e.g., adaptive thresholding, edge detection,';
                '    clustering, or morphological operations) to isolate target regions.';
                ' 4. Post-processes the segmented output to enhance accuracy by removing noise';
                '    and eliminating irrelevant small regions.';
                ' 5. Returns the segmented masks and/or overlays the results on the original frames.';
                '';
                ' INPUT PARAMETERS:';
                '';
                ' - inputFolder with frameData: Array or cell array containing the video frames/images to be segmented.';
                ' - parameters: Optional structure specifying segmentation settings (e.g., thresholds, filters, minimum region size, etc.).';
                ' - outputFolder: (Optional) Path to save the segmented images.';
                '';
                ' OUTPUT:';
                ' - Binary masks or segmented images, returned as arrays or saved to disk.';
                ' - (Optional) Interactive display of the segmentation results.';
                '';
                ' FEATURES:';
                '';
                ' - Fully automatic segmentation, with a small group of inputs by the user.';
                ' - Configurable for different domains through customizable parameters.';
                ' - Supports batch processing of long image or video sequences.';
                ' - Modular codebase, easy to extend with additional segmentation techniques.';
                '';
                ' REQUIREMENTS:';
                '';
                ' - MATLAB with the Image Processing Toolbox.';
                ' - MATLAB with the Deep Learning Toolbox.';
                ' - MATLAB with the Mapping Toolbox.';
                ' - MATLAB with the Medical Imaging Toolbox.';
                ' - MATLAB with the Computer Vision Toolbox.';
                ' - MATLAB with the Image Processing Toolbox Model for Segment Anything Model';
                ' - Images must be preloaded or acquired from file/video.';
                '';
                ' USAGE EXAMPLE:';
                '   AutomaticSegmentation(frameData, struct(''threshold'', 0.3, ''minArea'', 50), ''C:\segmented\'');';
                '';
                ' ==============================================================================';
                };

            % Create a dialog box with text
            fig = uifigure('Name', 'SAM Segmentation Tool Info', 'Position', [100 100 800 600]);
            txt = uitextarea(fig, 'Value', infoText, 'Position', [20 20 760 560], ...
                'FontName', 'FixedWidth', 'Editable', 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SAMAutomaticSegmentationToolUIFigure = uifigure('Visible', 'off');
            app.SAMAutomaticSegmentationToolUIFigure.Name = '(SAM) Automatic Segmentation Tool';
            app.SegmentationButton = uibutton(app.SAMAutomaticSegmentationToolUIFigure, 'push');
            app.SegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentationButtonPushed, true);
            app.SegmentationButton.Tag = 'SegmentationButton';
            app.SegmentationButton.Enable = 'off';
            app.SegmentationButton.Text = 'Segmentation';
            app.InputFolderButton = uibutton(app.SAMAutomaticSegmentationToolUIFigure, 'push');
            app.InputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @InputFolderButtonPushed, true);
            app.InputFolderButton.Tag = 'BrowseInputButton';
            app.InputFolderButton.Text = 'Input Folder';
            app.OutputFolderButton = uibutton(app.SAMAutomaticSegmentationToolUIFigure, 'push');
            app.OutputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OutputFolderButtonPushed, true);
            app.OutputFolderButton.Tag = 'BrowseOutputButton';
            app.OutputFolderButton.Text = 'Output Folder';
            app.LoadModelButton = uibutton(app.SAMAutomaticSegmentationToolUIFigure, 'push');
            app.LoadModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
            app.LoadModelButton.Text = 'Load Model';
            app.InputParametersPanel = uipanel(app.SAMAutomaticSegmentationToolUIFigure);
            app.InputParametersPanel.Title = 'Input Parameters';
            app.ScaleFactorEditFieldLabel = uilabel(app.InputParametersPanel);
            app.ScaleFactorEditFieldLabel.Text = 'Scale Factor';
            app.ScaleFactorEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.ScaleFactorEditField.Value = 0.3;
            app.DiskRadiusEditFieldLabel = uilabel(app.InputParametersPanel);
            app.DiskRadiusEditFieldLabel.Text = 'Disk Radius';
            app.DiskRadiusEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.DiskRadiusEditField.Value = 2;
            app.MinimumAreaObjectSizeEditFieldLabel = uilabel(app.InputParametersPanel);
            app.MinimumAreaObjectSizeEditFieldLabel.Text = 'Minimum Area Object Size';
            app.MinimumAreaObjectSizeEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.MinimumAreaObjectSizeEditField.Value = 100;
            app.AreaScalingFactorLabel = uilabel(app.InputParametersPanel);
            app.AreaScalingFactorLabel.Text = 'Area Scaling Factor';
            app.AreaScalingFactorEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.AreaScalingFactorEditField.Value = 0.005;
            app.Image = uiimage(app.InputParametersPanel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Icons8-Windows-8-Files-Add-List.512.png');
            app.SegmentationInfoPanel = uipanel(app.SAMAutomaticSegmentationToolUIFigure);
            app.SegmentationInfoPanel.Title = 'Segmentation Info Panel        ';
            app.InfoTextArea = uitextarea(app.SegmentationInfoPanel);
            app.InfoTextArea.Tag = 'InfoText Area';
            app.InputFolderEditField = uieditfield(app.SAMAutomaticSegmentationToolUIFigure, 'text');
            app.OutputFolderEditField = uieditfield(app.SAMAutomaticSegmentationToolUIFigure, 'text');
            app.SAMSegmentationToolButton = uibutton(app.SAMAutomaticSegmentationToolUIFigure, 'push');
            app.SAMSegmentationToolButton.ButtonPushedFcn = createCallbackFcn(app, @SAMSegmentationToolButtonPushed2, true);
            app.SAMSegmentationToolButton.Text = 'SAM Segmentation Tool';
            % Show the figure after all components are created
            app.SAMAutomaticSegmentationToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SAM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SAMAutomaticSegmentationToolUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SAMAutomaticSegmentationToolUIFigure)
        end
    end
end