classdef STABA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        STABAUIFigure              matlab.ui.Figure
        STABAButton                matlab.ui.control.Button
        SelectinputfolderButton    matlab.ui.control.Button
        EditField_4                matlab.ui.control.EditField
        FromEditField_4            matlab.ui.control.NumericEditField
        EditField_3                matlab.ui.control.EditField
        FromEditField_3            matlab.ui.control.NumericEditField
        EditField_2                matlab.ui.control.EditField
        FromEditField_2            matlab.ui.control.NumericEditField
        EditField                  matlab.ui.control.EditField
        FromEditField              matlab.ui.control.NumericEditField
        SetoutputfpsButton         matlab.ui.control.Button
        SetreferenceframeButton    matlab.ui.control.Button
        SelectoutputfolderButton   matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
        EditField2                 matlab.ui.control.EditField
        ButtonGroup                matlab.ui.container.ButtonGroup
        SURFButton                 matlab.ui.control.RadioButton
        GradientCorrelationButton  matlab.ui.control.RadioButton
    end

    properties (Access = private)
        FrameData
        VideoFrameRate
        SelectedAlgorithm = 'Gradient Correlation'; % Default Value
        OutputFolder
        inputFolder
        imageFiles
        ProgressBar

    end

    % V2

    methods (Access = private)

        function [sorted, index] = sort_nat(~, cellArray)
            % Natural order sort for strings with numbers (e.g., img1, img2, img10)

            expr = '\d+';
            numPart = regexp(cellArray, expr, 'match');

            numVal = cellfun(@(x) str2double(x{1}), numPart);
            [~, index] = sort(numVal);
            sorted = cellArray(index);
        end

        function updateProgressBar(app, message, value)
            % Update progress bar with message and value
            if isvalid(app.ProgressBar)
                app.ProgressBar.Message = message;
                app.ProgressBar.Value = value;
                drawnow;
            end
        end

        function closeProgressBar(app)
            % Close progress bar if it exists
            if ~isempty(app.ProgressBar) && isvalid(app.ProgressBar)
                close(app.ProgressBar);
                app.ProgressBar = [];
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FromEditField_2.Value = 0; % Default value for output FPS
            app.FromEditField_3.Value = 1; % Default reference frame
            app.FromEditField_4.Value = 0; % Initially 0 frames

            % Start pool in background (avoids delay on first Start)
            if isempty(gcp('nocreate'))
                parpool('local', 4);
            end
        end

        % Button pushed function: SetoutputfpsButton
        function SetoutputfpsButtonPushed(app, event)
            prompt = {'Enter the desired FPS:'}; % question
            dlgtitle = 'Set Output FPS'; % dialog title
            dims = [1 35];
            definput = {num2str(app.FromEditField.Value)}; % Default value
            response = inputdlg(prompt, dlgtitle, dims, definput);

            if ~isempty(response)
                fps_output = str2double(response{1});

                % Check if the input is a valid number
                if isnan(fps_output)
                    uialert(app.STABAUIFigure, 'Please enter a valid number.', 'Error');
                    return;
                end

                % Check if the value is negative or zero
                if fps_output <= 0
                    uialert(app.STABAUIFigure, 'FPS must be greater than zero.', 'Error');
                    return;
                end

                % If everything is valid, update the field
                app.FromEditField_2.Value = fps_output;
            end
        end

        % Button pushed function: SetreferenceframeButton
        function SetreferenceframeButtonPushed(app, event)
            prompt = {'Enter the reference frame number:'}; % question
            dlgtitle = 'Set Reference Frame'; % dialog title
            dims = [1 35];
            definput = {num2str(app.FromEditField_3.Value)}; % Default value
            response = inputdlg(prompt, dlgtitle, dims, definput);

            if ~isempty(response)
                frame_ref = str2double(response{1});

                % Check if the input is a valid number
                if isnan(frame_ref)
                    uialert(app.STABAUIFigure, 'Please enter a valid number.', 'Error');
                    return;
                end

                % Check if it is negative or zero
                if frame_ref <= 0
                    uialert(app.STABAUIFigure, 'The reference frame must be greater than zero.', 'Error');
                    return;
                end

                % Check if it exceeds the total number of frames
                total_frames = app.FromEditField_4.Value;
                if frame_ref > total_frames
                    uialert(app.STABAUIFigure, sprintf('The reference frame number (%g) cannot be greater than the total number of frames (%g).', ...
                        frame_ref, total_frames), 'Error');
                    return;
                end

                % If everything is valid, update the field
                app.FromEditField_3.Value = frame_ref;
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            app.SelectedAlgorithm = selectedButton.Text;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            temp = tic;

            app.StartButton.Enable = 'off';

            app.ProgressBar = uiprogressdlg(app.STABAUIFigure, 'Title', 'Processing', ...
                'Message', 'Starting stabilization...', 'Indeterminate', 'on');

            % Control input folder
            if isempty(app.inputFolder) || isempty(app.imageFiles)
                app.closeProgressBar();
                app.StartButton.Enable = 'on';
                uialert(app.STABAUIFigure, 'Please select an input folder first.', 'Error');
                return;
            end

            frameRate      = app.FromEditField_2.Value;
            referenceFrame = app.FromEditField_3.Value;
            outputFolder   = app.OutputFolder;

            if isempty(outputFolder)
                app.closeProgressBar();
                app.StartButton.Enable = 'on';
                uialert(app.STABAUIFigure, 'Please select an output folder first.', 'Error');
                return;
            end

            selectedMethod = app.ButtonGroup.SelectedObject.Text;

            try
                switch selectedMethod
                    case 'SURF'
                        app.updateProgressBar('Starting SURF stabilization...', 0.1);
                        app_stabilizer_SURF2_par([], outputFolder, frameRate, referenceFrame, app.inputFolder);
                        app.updateProgressBar('SURF stabilization completed!', 1.0);
                        uialert(app.STABAUIFigure, 'Stabilization with SURF completed!', 'Success');

                    case 'Gradient Correlation'
                        app.updateProgressBar('Starting Gradient Correlation stabilization...', 0.1);
                        app_stabilizer_gradientCorrelation_par([], outputFolder, frameRate, referenceFrame, app.inputFolder);
                        app.updateProgressBar('Gradient Correlation stabilization completed!', 1.0);
                        uialert(app.STABAUIFigure, 'Stabilization with Gradient Correlation completed!', 'Success');

                    otherwise
                        uialert(app.STABAUIFigure, 'Please select a stabilization method.', 'Error');
                end

            catch ME
                app.closeProgressBar();
                app.StartButton.Enable = 'on';
                uialert(app.STABAUIFigure, sprintf('An error occurred: %s', ME.message), 'Error');
                return;
            end

            app.closeProgressBar();
            app.StartButton.Enable = 'on';

            temp_tot = toc(temp);
            fprintf('Tempo totale: %.2f secondi\n', temp_tot);
        end

        % Button pushed function: SelectoutputfolderButton
        function SelectoutputfolderButtonPushed(app, event)
            folderPath = uigetdir('', 'Select the output folder');
            if folderPath ~= 0
                app.OutputFolder = folderPath;
                uialert(app.STABAUIFigure, ['Selected folder: ' folderPath], 'Output Folder');
            else
                uialert(app.STABAUIFigure, 'No folder selected.', 'Warning');
            end
        end

        % Button pushed function: SelectinputfolderButton
        function SelectinputfolderButtonPushed(app, event)
            app.inputFolder = uigetdir(pwd, 'Select frame folder');
            if app.inputFolder == 0
                uialert(app.STABAUIFigure, 'No folder selected', 'Warning');
                return;
            end

            app.imageFiles = dir(fullfile(app.inputFolder, '*.png'));
            if isempty(app.imageFiles)
                uialert(app.STABAUIFigure, 'No PNG files found in the folder', 'Error');
                return;
            end

            [~, idx] = app.sort_nat({app.imageFiles.name});
            app.imageFiles = app.imageFiles(idx);

            app.FrameData = {};
            app.FromEditField_4.Value = length(app.imageFiles);

            fps = 30;
            try
                if evalin('base', 'exist(''VideoStructure'', ''var'')')
                    VideoStructure = evalin('base', 'VideoStructure');
                    if isfield(VideoStructure, 'frameRate')
                        fps = VideoStructure.frameRate;
                        uialert(app.STABAUIFigure, sprintf('FPS loaded from ViFoSe: %.2f', fps), 'FPS Detected');
                    end
                else
                    uialert(app.STABAUIFigure, 'Using default FPS = 30. For original FPS, launch from ViFoSe.', 'Info', 'Icon', 'info');
                end
            catch ME
                uialert(app.STABAUIFigure, sprintf('Using default FPS = 30.\nError: %s', ME.message), 'Warning', 'Icon', 'warning');
            end

            app.VideoFrameRate = fps;
            app.FromEditField.Value = fps;
            app.FromEditField_2.Value = fps;

            uialert(app.STABAUIFigure, sprintf('Found %d frames.', length(app.imageFiles)), 'Success');
        end

        % Button pushed function: STABAButton
        function STABAButtonPushed(app, event)
            infoText = {
                ' STABA - STABilization Automatic Alignment';
                '';
                ' ==============================================================================';
                ' AUTHOR: Abdelrahman Abdelaziz Mohamed';
                '';
                ' DATE: 21/04/2026';
                '';
                ' FUNCTION: STABA - Automatic Video Stabilization Tool';
                '';
                ' PURPOSE:';
                '';
                ' This MATLAB application performs automatic video stabilization on sequences of';
                ' PNG image frames using advanced computer vision techniques with optimized parallel';
                ' processing. The tool reduces unwanted camera motion and jitter in video footage,';
                ' improving visual quality and stability for applications in scientific analysis,';
                ' microscopy, surveillance, cinematography, and multimedia production. Unlike manual';
                ' methods, STABA requires no user intervention during processing - just select your';
                ' parameters and let the algorithms handle the stabilization automatically.';
                '';
                ' ==============================================================================';
                ' CORE FEATURES';
                ' ==============================================================================';
                '';
                ' - Two High-Performance Stabilization Algorithms:';
                '   * SURF (Speeded-Up Robust Features): Feature-based approach using downsampled';
                '     images for 16x faster processing. Detects and matches distinctive image';
                '     features to estimate affine transformations (translation, rotation, scaling).';
                '   * Gradient Correlation: Intensity-based method using gradient correlation for';
                '     pure translational alignment. Faster than SURF, ideal for camera shake.';
                '';
                ' - Parallel Processing Architecture:';
                '   * Automatic parallel pool initialization (4 workers) on startup';
                '   * Transform computation parallelized across all frames';
                '   * Block-based video writing (20 frames per block) for memory efficiency';
                '   * Scales linearly with CPU core count for improved performance';
                '';
                ' - Memory-Optimized Design:';
                '   * Frames loaded from disk on-demand, not stored in RAM';
                '   * Downsampling to 25% resolution for feature detection (16x speedup)';
                '   * Block processing prevents memory saturation on long sequences';
                '   * Handles sequences of any length without memory constraints';
                '';
                ' - Platform-Aware Output:';
                '   * Automatic codec selection: MPEG-4 (.mp4) for Windows/Mac';
                '   * Motion JPEG AVI (.avi) for Linux/Unix systems';
                '   * Output filename includes input folder name for easy identification';
                '';
                ' ==============================================================================';
                ' ALGORITHM DETAILS';
                ' ==============================================================================';
                '';
                ' SURF STABILIZATION (app_stabilizer_SURF2_par):';
                ' - Detection: SURF features on 25% downsampled grayscale images';
                ' - Matching: Unique feature matches between current and reference frames';
                ' - Transform: Affine (6 DOF: translation, rotation, scaling, shearing)';
                ' - Minimum Matches: 3 points required for reliable transform estimation';
                ' - Scaling: Translation rescaled from downsampled to full resolution';
                ' - Fallback: Original frame used if matching fails or errors occur';
                ' - Output: [folder]_stabilized_SURF.mp4 (or .avi on Linux)';
                '';
                ' GRADIENT CORRELATION STABILIZATION (app_stabilizer_gradientCorrelation_par):';
                ' - Method: imregcorr intensity-based gradient correlation';
                ' - Detection: Gradient information on 25% downsampled grayscale images';
                ' - Transform: Translation only (2 DOF: horizontal and vertical shift)';
                ' - Scaling: Translation rescaled by factor of 4 (1/0.25) to full resolution';
                ' - Advantages: Works on low-texture regions, faster than feature-based';
                ' - Best For: Pure camera shake without rotation or zoom';
                ' - Output: [folder]_stabilized_GRADIENT.mp4 (or .avi on Linux)';
                '';
                ' ==============================================================================';
                ' WORKFLOW';
                ' ==============================================================================';
                '';
                ' 1. Input Folder Selection:';
                '    - User selects folder containing sequential PNG frames';
                '    - Frames automatically sorted using natural number ordering';
                '    - Total frame count displayed in interface';
                '    - FPS auto-detected from ViFoSe VideoStructure if available (default: 30)';
                '';
                ' 2. Parameter Configuration:';
                '    - Set Output FPS: Frame rate for stabilized video (default: input FPS)';
                '    - Set Reference Frame: Frame index to use as alignment target (default: 1)';
                '    - Select Algorithm: Choose between SURF or Gradient Correlation';
                '    - Select Output Folder: Destination for stabilized video';
                '';
                ' 3. Processing Pipeline:';
                '    - Parallel pool initialized (4 workers)';
                '    - Reference frame loaded and downsampled to 25%';
                '    - Features/gradients extracted from reference';
                '    - For each non-reference frame (in parallel):';
                '      * Load frame from disk';
                '      * Downsample to 25%';
                '      * Detect features/compute gradients';
                '      * Match with reference and estimate transform';
                '      * Rescale translation to full resolution';
                '    - Frames processed in blocks of 20:';
                '      * Load block from disk';
                '      * Apply computed transforms';
                '      * Write block to video sequentially';
                '    - Video finalized and saved';
                '';
                ' 4. Output Generation:';
                '    - Stabilized video saved with descriptive filename';
                '    - Console confirmation with full output path';
                '    - Total processing time reported';
                '';
                ' ==============================================================================';
                ' INPUT PARAMETERS';
                ' ==============================================================================';
                '';
                ' - inputFolder: Directory containing PNG frame sequence (required)';
                ' - outputFolder: Destination directory for stabilized video (required)';
                ' - referenceFrame: Index of frame to use as alignment reference (1-N)';
                ' - outputFPS: Desired frame rate for output video (must be > 0)';
                ' - algorithm: "SURF" or "Gradient Correlation" (selected via radio buttons)';
                '';
                ' ==============================================================================';
                ' OUTPUT';
                ' ==============================================================================';
                '';
                ' - Stabilized video file:';
                '   * SURF: [inputFolderName]_stabilized_SURF.mp4 (.avi on Linux)';
                '   * Gradient: [inputFolderName]_stabilized_GRADIENT.mp4 (.avi on Linux)';
                ' - Original video dimensions preserved';
                ' - User-specified frame rate applied';
                ' - Motion artifacts significantly reduced';
                '';
                ' ==============================================================================';
                ' PERFORMANCE CHARACTERISTICS';
                ' ==============================================================================';
                '';
                ' - Speed: ~16x faster than full-resolution processing via downsampling';
                ' - Memory: O(blockSize) = O(20 frames) regardless of sequence length';
                ' - Scalability: Linear speedup with CPU core count (up to 4 cores)';
                ' - Throughput: Typical processing rate 5-15 fps depending on resolution';
                '';
                ' ==============================================================================';
                ' REQUIREMENTS';
                ' ==============================================================================';
                '';
                ' - MATLAB R2020b or newer (recommended)';
                ' - Image Processing Toolbox (for imresize, im2gray, imwarp, imtranslate)';
                ' - Computer Vision Toolbox (for SURF: detectSURFFeatures, matchFeatures)';
                ' - Computer Vision Toolbox (for Gradient: imregcorr)';
                ' - Parallel Computing Toolbox (required for parfor, parpool)';
                ' - Input frames must be PNG format and sequentially numbered';
                ' - Sufficient disk space for output video (typically 10-50 MB per minute)';
                '';
                ' ==============================================================================';
                ' USAGE INSTRUCTIONS';
                ' ==============================================================================';
                '';
                ' 1. Click "Select input folder" to choose directory containing PNG frames';
                ' 2. Verify total frame count and suggested FPS in interface';
                ' 3. Click "Select output folder" to choose destination for stabilized video';
                ' 4. Click "Set output fps" to adjust frame rate (optional, defaults to input FPS)';
                ' 5. Click "Set reference frame" to choose alignment target (optional, default: 1)';
                ' 6. Select stabilization algorithm:';
                '    - "Gradient Correlation" for pure camera shake (default, faster)';
                '    - "SURF" for complex motion with rotation/scaling (slower, more robust)';
                ' 7. Click "Start" to begin stabilization';
                ' 8. Wait for completion message and check output folder for stabilized video';
                '';
                ' ==============================================================================';
                ' ALGORITHM SELECTION GUIDE';
                ' ==============================================================================';
                '';
                ' USE GRADIENT CORRELATION WHEN:';
                ' - Camera motion is primarily translational shake';
                ' - Scene has low texture or homogeneous regions';
                ' - Processing speed is critical';
                ' - Camera orientation remains constant';
                '';
                ' USE SURF WHEN:';
                ' - Camera motion includes rotation or zoom';
                ' - Scene has distinctive features and good texture';
                ' - More robust alignment is needed';
                ' - Willing to accept longer processing time';
                '';
                ' ==============================================================================';
                ' LIMITATIONS';
                ' ==============================================================================';
                '';
                ' - SURF: May fail on low-texture scenes (e.g., blank walls, clear sky)';
                ' - SURF: Requires minimum 3 feature matches per frame';
                ' - Gradient: Cannot correct rotation, scaling, or perspective distortion';
                ' - Both: Assumes sufficient frame-to-frame overlap for alignment';
                ' - Both: Works only with PNG input frames (no JPEG, TIFF, or other formats)';
                ' - Both: Reference frame size determines output dimensions (no cropping)';
                ' - Both: Large motions may exceed transform estimation capabilities';
                '';
                ' ==============================================================================';
                ' ERROR HANDLING';
                ' ==============================================================================';
                '';
                ' - Input validation: Checks for missing folders, empty frame lists';
                ' - Parameter validation: Ensures FPS > 0, reference frame within range';
                ' - Algorithm fallback: Original frame used if transform estimation fails';
                ' - Progress tracking: Real-time progress dialog with status messages';
                ' - Error reporting: Detailed error messages displayed via uialert';
                ' - Graceful recovery: Start button re-enabled after errors';
                '';
                ' ==============================================================================';
                ' INTEGRATION WITH ViFoSe';
                ' ==============================================================================';
                '';
                ' STABA can be launched from ViFoSe (Video Frame Separation tool):';
                ' - Automatically inherits FPS from VideoStructure.frameRate';
                ' - Seamless workflow: extract frames → stabilize → analyze';
                ' - Standalone operation: Can also run independently with manual FPS entry';
                '';
                ' ==============================================================================';
                ' TECHNICAL NOTES FOR DEVELOPERS';
                ' ==============================================================================';
                '';
                ' - Parallel Pool: Pre-initialized on app startup to avoid first-run delay';
                ' - Natural Sorting: Custom sort_nat function handles numeric frame names';
                ' - Progress Bar: Managed via updateProgressBar and closeProgressBar methods';
                ' - Memory Strategy: Frame_Data property remains empty (not used in optimized version)';
                ' - Platform Detection: isunix && ~ismac identifies Linux for codec selection';
                ' - Error Handling: Try-catch blocks in StartButtonPushed for robustness';
                ' - UI State: Start button disabled during processing to prevent double-clicks';
                '';
                ' ==============================================================================';
                ' VERSION HISTORY';
                ' ==============================================================================';
                '';
                ' Optimized Parallel Processing';
                ' - Added 25% downsampling for 16x speed improvement';
                ' - Implemented block-based video writing for memory efficiency';
                ' - Removed pre-loading of all frames to reduce RAM usage';
                ' - Added platform-aware codec selection';
                ' - Parallel pool pre-initialization on startup';
                '';
                };

            % Create a dialog box with the text
            fig = uifigure('Name', 'STABA Stabilization Tool Info', 'Position', [100 100 800 600]);
            txt = uitextarea(fig, 'Value', infoText, 'Position', [20 20 760 560], ...
                'FontName', 'FixedWidth', 'Editable', 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.STABAUIFigure = uifigure('Visible', 'off');
            app.STABAUIFigure.Name = 'STABA';
            app.ButtonGroup = uibuttongroup(app.STABAUIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.GradientCorrelationButton = uiradiobutton(app.ButtonGroup);
            app.GradientCorrelationButton.Text = 'Gradient Correlation';
            app.GradientCorrelationButton.Value = true;
            app.SURFButton = uiradiobutton(app.ButtonGroup);
            app.SURFButton.Text = 'SURF';
            app.EditField2 = uieditfield(app.STABAUIFigure, 'text');
            app.EditField2.Value = 'Selected algorithm:';
            app.StartButton = uibutton(app.STABAUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SelectoutputfolderButton = uibutton(app.STABAUIFigure, 'push');
            app.SelectoutputfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectoutputfolderButtonPushed, true);
            app.SelectoutputfolderButton.Text = 'Select output folder';
            app.SetreferenceframeButton = uibutton(app.STABAUIFigure, 'push');
            app.SetreferenceframeButton.ButtonPushedFcn = createCallbackFcn(app, @SetreferenceframeButtonPushed, true);
            app.SetreferenceframeButton.Text = 'Set reference frame';
            app.SetoutputfpsButton = uibutton(app.STABAUIFigure, 'push');
            app.SetoutputfpsButton.ButtonPushedFcn = createCallbackFcn(app, @SetoutputfpsButtonPushed, true);
            app.SetoutputfpsButton.Text = 'Set output fps';
            app.FromEditField = uieditfield(app.STABAUIFigure, 'numeric');
            app.EditField = uieditfield(app.STABAUIFigure, 'text');
            app.EditField.Value = 'Suggested:';
            app.FromEditField_2 = uieditfield(app.STABAUIFigure, 'numeric');
            app.EditField_2 = uieditfield(app.STABAUIFigure, 'text');
            app.EditField_2.Value = 'Selected:';
            app.FromEditField_3 = uieditfield(app.STABAUIFigure, 'numeric');
            app.FromEditField_3.Value = 1;
            app.EditField_3 = uieditfield(app.STABAUIFigure, 'text');
            app.EditField_3.Value = 'Selected:';
            app.FromEditField_4 = uieditfield(app.STABAUIFigure, 'numeric');
            app.EditField_4 = uieditfield(app.STABAUIFigure, 'text');
            app.EditField_4.Value = 'Total:';
            app.SelectinputfolderButton = uibutton(app.STABAUIFigure, 'push');
            app.SelectinputfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectinputfolderButtonPushed, true);
            app.SelectinputfolderButton.Text = 'Select input folder';
            app.STABAButton = uibutton(app.STABAUIFigure, 'push');
            app.STABAButton.ButtonPushedFcn = createCallbackFcn(app, @STABAButtonPushed, true);
            app.STABAButton.Text = 'STABA';
            % Show the figure after all components are created
            app.STABAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = STABA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.STABAUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.STABAUIFigure)
        end
    end
end