classdef STABM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        STABMUIFigure             matlab.ui.Figure
        CheckAlignmentButton      matlab.ui.control.Button
        STABMButton               matlab.ui.control.Button
        SelectinputfolderButton   matlab.ui.control.Button
        StartButton               matlab.ui.control.Button
        EditField_2               matlab.ui.control.EditField
        FromEditField_2           matlab.ui.control.NumericEditField
        EditField                 matlab.ui.control.EditField
        FromEditField             matlab.ui.control.NumericEditField
        SetoutputfpsButton        matlab.ui.control.Button
        SelectoutputfolderButton  matlab.ui.control.Button
    end

    properties (Access = private)
        FrameData
        VideoFrameRate
        OutputFolder
        inputFolder
        imageFiles
        ProgressBar
        AlignmentThreshold
    end

    % V2

    methods (Access = private)

        function [sorted, index] = sort_nat(~, cellArray)
            % Natural order sort for strings with numbers (e.g., img1, img2, img10)

            expr = '\d+';
            numPart = regexp(cellArray, expr, 'match');

            numVal = cellfun(@(x) str2double(x{1}), numPart);
            [~, index] = sort(numVal);
            sorted = cellArray(index);
        end


        function updateProgressBar(app, message, value)
            % Update progress bar with message and value
            if isvalid(app.ProgressBar)
                app.ProgressBar.Message = message;
                app.ProgressBar.Value = value;
                drawnow;
            end
        end

        function closeProgressBar(app)
            % Close progress bar if it exists
            if ~isempty(app.ProgressBar) && isvalid(app.ProgressBar)
                close(app.ProgressBar);
                app.ProgressBar = [];
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FromEditField_2.Value = 0; % Default value for output FPS
        end

        % Button pushed function: SetoutputfpsButton
        function SetoutputfpsButtonPushed(app, event)
            prompt = {'Enter the desired FPS:'}; % question
            dlgtitle = 'Set Output FPS'; % dialog title
            dims = [1 35];
            definput = {num2str(app.FromEditField.Value)}; % Default value
            response = inputdlg(prompt, dlgtitle, dims, definput);

            if ~isempty(response)
                fps_output = str2double(response{1});

                % Check if the input is a valid number
                if isnan(fps_output)
                    uialert(app.STABMUIFigure, 'Please enter a valid number.', 'Error');
                    return;
                end

                % Check if the value is negative or zero
                if fps_output <= 0
                    uialert(app.STABMUIFigure, 'FPS must be greater than zero.', 'Error');
                    return;
                end

                % If everything is valid, update the field
                app.FromEditField_2.Value = fps_output;
            end
        end

        % Button pushed function: SelectoutputfolderButton
        function SelectoutputfolderButtonPushed(app, event)
            folderPath = uigetdir('', 'Select the output folder');
            if folderPath ~= 0
                app.OutputFolder = folderPath;
                uialert(app.STABMUIFigure, ['Selected folder: ' folderPath], 'Output Folder');
            else
                uialert(app.STABMUIFigure, 'No folder selected.', 'Warning');
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % This function performs manual video stabilization by having the user
            % select the same physical point in each frame for precise alignment

            tic;  % Start timing

            % ========================================================================
            % STEP 1: VALIDATE INPUT/OUTPUT FOLDERS
            % ========================================================================
            % Check that input folder has been selected
            if isempty(app.inputFolder) || isempty(app.imageFiles)
                uialert(app.STABMUIFigure, 'Please select an input folder first.', 'Error');
                return;
            end

            % Check output folder
            outputFolder = app.OutputFolder;
            if isempty(outputFolder)
                uialert(app.STABMUIFigure, 'Please select an output folder first.', 'Error');
                return;
            end

            % ========================================================================
            % STEP 2: LOAD FRAMES INTO MEMORY
            % ========================================================================
            % Load frames only now (lazy loading)
            if isempty(app.FrameData)
                d = uiprogressdlg(app.STABMUIFigure, 'Title', 'Loading', ...
                    'Message', 'Loading frames...', 'Indeterminate', 'on');
                n = length(app.imageFiles);
                Frame_Data = cell(1, n);
                for i = 1:n
                    Frame_Data{i} = imread(fullfile(app.inputFolder, app.imageFiles(i).name));
                end
                app.FrameData = Frame_Data;
                close(d);
            end

            % Retrieve frame data and total number of frames
            Frame_Data = app.FrameData;
            numFrames  = numel(Frame_Data);

            % ========================================================================
            % STEP 3: COLLECT FRAME RANGES FROM USER
            % ========================================================================
            % Collect multiple frame ranges
            allSelectedFrames = [];
            continueAdding    = true;

            while continueAdding
                prompt   = {'Start frame index:', 'End frame index:'};
                dlgtitle = sprintf('Add Frame Range (selected so far: %d frames)', ...
                    length(allSelectedFrames));
                dims     = [1 60];
                definput = {'1', num2str(numFrames)};
                risposta = inputdlg(prompt, dlgtitle, dims, definput);

                % If user presses Cancel, stop adding ranges
                if isempty(risposta)
                    break;
                end

                startFrame = str2double(risposta{1});
                endFrame   = str2double(risposta{2});

                % Validate range
                if isnan(startFrame) || isnan(endFrame) || startFrame < 1 || ...
                        endFrame > numFrames || startFrame > endFrame
                    uialert(app.STABMUIFigure, 'Invalid frame range.', 'Error');
                    continue;
                end

                % Add frames to selection (no duplicates)
                newFrames         = startFrame:endFrame;
                allSelectedFrames = unique([allSelectedFrames, newFrames]);

                % Ask if user wants to add another range
                choice = uiconfirm(app.STABMUIFigure, ...
                    sprintf('Added frames %d-%d.\nCurrent selection: %d frames total.\n\nAdd another range?', ...
                    startFrame, endFrame, length(allSelectedFrames)), ...
                    'Add Range', ...
                    'Options', {'Add another range', 'Done'}, ...
                    'DefaultOption', 'Add another range');

                if strcmp(choice, 'Done')
                    continueAdding = false;
                end
            end

            % Exit if no frames were selected
            if isempty(allSelectedFrames)
                return;
            end

            % ========================================================================
            % STEP 4: INITIALIZE DATA STRUCTURES
            % ========================================================================
            % Sort selected frames
            selectedFrames = sort(allSelectedFrames);
            nSelected      = numel(selectedFrames);
            alignedFrames  = Frame_Data;

            % Array to store user click coordinates [frame_index, x, y]
            userPoints    = zeros(nSelected, 3);
            hasValidClick = false(nSelected, 1);

            % Common target coordinates (set from first frame click)
            commonTargetX = 0;
            commonTargetY = 0;

            % ========================================================================
            % STEP 5: PHASE 1 - MANUAL POINT ACQUISITION FOR EACH FRAME
            % ========================================================================
            % Create progress bar
            d = uiprogressdlg(app.STABMUIFigure, ...
                'Title', 'Point Selection - Manual Mode', ...
                'Message', 'Initializing...', ...
                'Indeterminate', 'off', ...
                'Cancelable', 'off');

            % PHASE 1: Manual point acquisition for each frame
            for idx = 1:nSelected
                i            = selectedFrames(idx);
                currentFrame = Frame_Data{i};

                % Update progress bar
                d.Value   = idx / nSelected;
                d.Message = sprintf('Aligning frame %d of %d  |  Original frame index: %d of %d', ...
                    idx, nSelected, i, numFrames);
                drawnow;

                % Open figure for point selection
                f = figure('Name', sprintf('[Frame %d of %d to align  |  Original index: %d of %d] - Select REFERENCE POINT', ...
                    idx, nSelected, i, numFrames), ...
                    'NumberTitle', 'off', ...
                    'Position', [100 100 800 600]);

                imshow(currentFrame);
                drawnow;

                % Enable zoom and pan for precise point location
                zoom on; pan on;
                title({sprintf('Frame %d of %d to align  |  Original frame index: %d of %d', ...
                    idx, nSelected, i, numFrames), ...
                    'ZOOM AND PAN to find the EXACT SAME PHYSICAL POINT', ...
                    'Press ENTER when ready to click on the point'}, ...
                    'FontSize', 10, 'FontWeight', 'bold');

                % Wait for ENTER key before allowing click
                waitforbuttonpress;
                keyPressed = get(gcf, 'CurrentCharacter');
                while ~strcmp(keyPressed, char(13))
                    waitforbuttonpress;
                    keyPressed = get(gcf, 'CurrentCharacter');
                end

                % Disable zoom/pan before point selection
                zoom off; pan off;
                title({sprintf('Frame %d of %d to align  |  Original frame index: %d of %d', ...
                    idx, nSelected, i, numFrames), ...
                    'CLICK on the EXACT SAME PHYSICAL POINT as in previous frames'}, ...
                    'FontSize', 10, 'FontWeight', 'bold', 'Color', 'red');

                % Show target from first frame as reference (for frames after the first)
                if idx > 1 && hasValidClick(1)
                    firstPoint = userPoints(1, 2:3);
                    hold on;
                    plot(firstPoint(1), firstPoint(2), 'g+', 'MarkerSize', 20, 'LineWidth', 3);
                    plot(firstPoint(1), firstPoint(2), 'go', 'MarkerSize', 15, 'LineWidth', 2);
                    text(firstPoint(1) + 15, firstPoint(2) - 15, 'Target position', ...
                        'Color', 'green', 'FontSize', 10, 'FontWeight', 'bold', ...
                        'BackgroundColor', 'black');
                    hold off;
                    title({sprintf('Frame %d of %d to align  |  Original frame index: %d of %d', ...
                        idx, nSelected, i, numFrames), ...
                        sprintf('AIM FOR THE GREEN CROSS (Target: %.1f, %.1f)', ...
                        firstPoint(1), firstPoint(2))}, ...
                        'FontSize', 10, 'FontWeight', 'bold');
                end

                % Loop until a valid click is obtained
                validClick = false;
                while ~validClick
                    try
                        [xClick, yClick] = ginput(1);
                    catch
                        % Handle case where figure was closed accidentally
                        close(f);
                        f = figure('Name', sprintf('[Frame %d of %d to align  |  Original index: %d of %d] - Select REFERENCE POINT', ...
                            idx, nSelected, i, numFrames), ...
                            'NumberTitle', 'off', ...
                            'Position', [100 100 800 600], ...
                            'MenuBar', 'none', ...
                            'ToolBar', 'none');
                        imshow(currentFrame);
                        title({sprintf('Frame %d of %d to align  |  Original frame index: %d of %d', ...
                            idx, nSelected, i, numFrames), ...
                            'Please click on the point'}, ...
                            'FontSize', 10, 'FontWeight', 'bold');
                        continue;
                    end

                    % Verify click is within image boundaries
                    if ~isempty(xClick) && ~isempty(yClick) && ...
                            xClick >= 1 && xClick <= size(currentFrame, 2) && ...
                            yClick >= 1 && yClick <= size(currentFrame, 1)

                        validClick         = true;
                        userPoints(idx, :) = [i, xClick, yClick];
                        hasValidClick(idx) = true;

                        % Set common target from first frame
                        if idx == 1
                            commonTargetX = xClick;
                            commonTargetY = yClick;
                            targetMsg     = 'FIRST POINT SELECTED - This will be the target for all frames';
                        else
                            % Calculate distance from target for subsequent frames
                            distance  = sqrt((xClick - commonTargetX)^2 + (yClick - commonTargetY)^2);
                            targetMsg = sprintf('Distance from target: %.1f pixels', distance);
                        end

                        % Visualize selected point and target
                        hold on;
                        plot(xClick, yClick, 'r+', 'MarkerSize', 25, 'LineWidth', 3);
                        plot(xClick, yClick, 'ro', 'MarkerSize', 20, 'LineWidth', 2);
                        text(xClick + 20, yClick - 20, sprintf('Selected: (%.1f, %.1f)', xClick, yClick), ...
                            'Color', 'red', 'FontSize', 10, 'FontWeight', 'bold', ...
                            'BackgroundColor', 'black');

                        if idx > 1
                            % Show target and displacement vector
                            plot(commonTargetX, commonTargetY, 'g+', 'MarkerSize', 25, 'LineWidth', 3);
                            plot(commonTargetX, commonTargetY, 'go', 'MarkerSize', 20, 'LineWidth', 2);
                            text(commonTargetX + 20, commonTargetY + 20, ...
                                sprintf('Target: (%.1f, %.1f)', commonTargetX, commonTargetY), ...
                                'Color', 'green', 'FontSize', 10, 'FontWeight', 'bold', ...
                                'BackgroundColor', 'black');
                            plot([xClick, commonTargetX], [yClick, commonTargetY], 'y--', 'LineWidth', 2);
                        end
                        hold off;

                        title({sprintf('Frame %d of %d to align  |  Original frame index: %d of %d', ...
                            idx, nSelected, i, numFrames), ...
                            targetMsg, 'Loading next frame...'}, ...
                            'FontSize', 10, 'FontWeight', 'bold');

                        pause(3);  % Give user time to review selection
                    else
                        % Invalid click - outside image boundaries
                        title({sprintf('Frame %d of %d to align  |  Original frame index: %d of %d', ...
                            idx, nSelected, i, numFrames), ...
                            'Invalid click! Click inside the image.'}, ...
                            'FontSize', 10, 'FontWeight', 'bold', 'Color', 'red');
                    end
                end

                close(f);
            end

            close(d);

            % ========================================================================
            % STEP 6: VERIFY ALL FRAMES HAVE VALID CLICKS
            % ========================================================================
            % Verify all frames have a valid click
            framesWithoutClick = find(~hasValidClick);
            if ~isempty(framesWithoutClick)
                missingFrames = selectedFrames(framesWithoutClick);
                errorMsg = sprintf('Missing points in frames: %s\n\nAll frames must have a selected point.', ...
                    num2str(missingFrames));
                uialert(app.STABMUIFigure, errorMsg, 'Error', 'Icon', 'error');
                return;
            end

            % ========================================================================
            % STEP 7: PHASE 2 - APPLY TRANSLATIONS TO ALIGN POINTS
            % ========================================================================
            % PHASE 2: Apply translations to align all points to the common target
            d = uiprogressdlg(app.STABMUIFigure, ...
                'Title', 'Applying Manual Alignment...', ...
                'Message', 'Aligning all points to common target...', ...
                'Indeterminate', 'off', ...
                'Cancelable', 'off');

            framesAligned = 0;

            for idx = 1:nSelected
                i     = selectedFrames(idx);
                userX = userPoints(idx, 2);
                userY = userPoints(idx, 3);

                % Calculate translation needed to bring point to common target
                dx = commonTargetX - userX;
                dy = commonTargetY - userY;

                % Apply translation to align frame
                alignedFrames{i} = imtranslate(Frame_Data{i}, [dx, dy]);
                framesAligned     = framesAligned + 1;

                d.Value   = idx / nSelected;
                d.Message = sprintf('Aligning frame %d of %d... (dx=%.1f, dy=%.1f)', ...
                    idx, nSelected, dx, dy);
            end

            % ========================================================================
            % STEP 8: PHASE 3 - SAVE VIDEO
            % ========================================================================
            % PHASE 3: Save video
            fps = app.FromEditField_2.Value;

            [~, baseName]    = fileparts(app.inputFolder);
            outputVideoName  = baseName + "_manual_aligned.mp4";
            outputPath       = fullfile(outputFolder, outputVideoName);

            writer           = VideoWriter(outputPath, 'MPEG-4');
            writer.FrameRate = fps;
            open(writer);

            % Write all frames (aligned and non-aligned) to video
            for i = 1:numFrames
                writeVideo(writer, alignedFrames{i});
            end

            close(writer);
            d.Value   = 1;
            d.Message = 'Done!';
            pause(0.5);
            close(d);

            % ========================================================================
            % STEP 9: CALCULATE DISPLACEMENT STATISTICS
            % ========================================================================
            % Calculate displacement statistics
            displacements = zeros(nSelected, 2);
            for idx = 1:nSelected
                displacements(idx, 1) = commonTargetX - userPoints(idx, 2);
                displacements(idx, 2) = commonTargetY - userPoints(idx, 3);
            end

            maxDx  = max(abs(displacements(:,1)));
            maxDy  = max(abs(displacements(:,2)));
            meanDx = mean(abs(displacements(:,1)));
            meanDy = mean(abs(displacements(:,2)));

            % ========================================================================
            % STEP 10: DISPLAY SUMMARY TO USER
            % ========================================================================
            summaryMsg = sprintf(['MANUAL ALIGNMENT COMPLETED\n\n' ...
                'Common target position: (%.1f, %.1f)\n' ...
                'Frames aligned: %d\n\n' ...
                'Maximum displacement:\n' ...
                '  X: %.1f pixels\n' ...
                '  Y: %.1f pixels\n\n' ...
                'Average displacement:\n' ...
                '  X: %.1f pixels\n' ...
                '  Y: %.1f pixels\n\n' ...
                'All user clicks now appear at the same coordinates.'], ...
                commonTargetX, commonTargetY, framesAligned, ...
                maxDx, maxDy, meanDx, meanDy);

            uialert(app.STABMUIFigure, summaryMsg, 'Alignment Complete', 'Icon', 'success');

            % ========================================================================
            % STEP 11: SAVE ALIGNMENT METADATA
            % ========================================================================
            % Save alignment metadata
            alignmentData.commonTarget   = [commonTargetX, commonTargetY];
            alignmentData.userPoints     = userPoints;
            alignmentData.displacements  = displacements;
            alignmentData.selectedFrames = selectedFrames;
            alignmentData.timestamp      = datetime('now');

            dataFileName = fullfile(outputFolder, [baseName '_manual_aligned_data.mat']);
            save(dataFileName, 'alignmentData');
            fprintf('Alignment data saved to: %s\n', dataFileName);

            % ========================================================================
            % STEP 12: DISPLAY DISPLACEMENT PLOTS
            % ========================================================================
            % Display displacement plots
            figure('Name', 'Manual Alignment Displacements', ...
                'NumberTitle', 'off', 'Position', [100 100 900 500]);

            % Plot 1: Horizontal displacement over frames
            subplot(2,2,1);
            plot(selectedFrames, displacements(:,1), 'r-o', 'LineWidth', 2, 'MarkerSize', 6);
            xlabel('Frame Number'); ylabel('X Displacement (pixels)');
            title('Horizontal Displacement (dx)'); grid on; hold on;
            plot([selectedFrames(1), selectedFrames(end)], [meanDx, meanDx], 'r--', 'LineWidth', 1.5);
            legend('Displacement', sprintf('Mean: %.1f', meanDx));

            % Plot 2: Vertical displacement over frames
            subplot(2,2,2);
            plot(selectedFrames, displacements(:,2), 'b-s', 'LineWidth', 2, 'MarkerSize', 6);
            xlabel('Frame Number'); ylabel('Y Displacement (pixels)');
            title('Vertical Displacement (dy)'); grid on; hold on;
            plot([selectedFrames(1), selectedFrames(end)], [meanDy, meanDy], 'b--', 'LineWidth', 1.5);
            legend('Displacement', sprintf('Mean: %.1f', meanDy));

            % Plot 3: Spatial distribution of user clicks
            subplot(2,2,3);
            plot(userPoints(:,2), userPoints(:,3), 'ro', 'MarkerSize', 8, 'LineWidth', 2);
            hold on;
            plot(commonTargetX, commonTargetY, 'g+', 'MarkerSize', 20, 'LineWidth', 3);
            plot(commonTargetX, commonTargetY, 'go', 'MarkerSize', 15, 'LineWidth', 2);
            xlabel('Original X coordinate (pixels)'); ylabel('Original Y coordinate (pixels)');
            title('Original User Click Positions'); grid on;
            legend('User clicks', 'Common target', 'Location', 'best');

            sgtitle(sprintf('Manual Alignment Analysis - Target: (%.1f, %.1f)', ...
                commonTargetX, commonTargetY));

            % ========================================================================
            % STEP 13: REPORT PROCESSING TIME
            % ========================================================================
            temp = toc;
            fprintf('Processing time: %.2f seconds\n', temp);
        end

        % Button pushed function: SelectinputfolderButton
        function SelectinputfolderButtonPushed(app, event)
            app.inputFolder = uigetdir(pwd, 'Select frame folder');

            if app.inputFolder == 0
                uialert(app.STABMUIFigure, 'No folder selected', 'Warning');
                return;
            end

            app.imageFiles = dir(fullfile(app.inputFolder, '*.png'));
            if isempty(app.imageFiles)
                uialert(app.STABMUIFigure, 'No PNG files found in the folder', 'Error');
                return;
            end

            [~, idx] = app.sort_nat({app.imageFiles.name});
            app.imageFiles = app.imageFiles(idx);

            app.FrameData = {};

            fps = 30;
            try
                if evalin('base', 'exist(''VideoStructure'', ''var'')')
                    VideoStructure = evalin('base', 'VideoStructure');
                    if isfield(VideoStructure, 'frameRate')
                        fps = VideoStructure.frameRate;
                        uialert(app.STABMUIFigure, sprintf('FPS loaded from ViFoSe: %.2f', fps), 'FPS Detected');
                    else
                        error('VideoStructure exists but frameRate field not found');
                    end
                else
                    uialert(app.STABMUIFigure, ...
                        'Using default FPS = 30. For original FPS, launch from ViFoSe.', ...
                        'Info', 'Icon', 'info');
                end
            catch ME
                uialert(app.STABMUIFigure, ...
                    sprintf('Could not read video FPS. Using default FPS = 30.\nError: %s', ME.message), ...
                    'Warning', 'Icon', 'warning');
            end

            app.VideoFrameRate = fps;
            app.FromEditField.Value = fps;
            app.FromEditField_2.Value = fps;

            uialert(app.STABMUIFigure, sprintf('Found %d frames.', length(app.imageFiles)), 'Success');
        end

        % Button pushed function: STABMButton
        function STABMButtonPushed(app, event)
            infoText = {
                ' STABM - MANUAL VIDEO STABILIZATION TOOL';
                '';
                ' ==============================================================================';
                ' AUTHOR: Abdelrahman Abdelaziz Mohamed';
                '';
                ' DATE: 21/04/2026';
                '';
                ' FUNCTION: STABM - Manual Frame Alignment and Quality Control';
                '';
                ' PURPOSE:';
                '';
                ' This MATLAB application performs manual video stabilization by allowing users';
                ' to select a common reference point in each frame for precise alignment. Unlike';
                ' automatic template-matching approaches, this method relies entirely on user';
                ' input: the operator manually identifies the same physical point in every frame.';
                ' The algorithm then calculates and applies pure translational transformations to';
                ' bring all selected points to identical coordinates in the output video. STABM';
                ' also includes an alignment quality checker that verifies frame alignment using';
                ' Mean Absolute Difference (MAD) analysis on user-defined ROIs. This dual';
                ' approach is designed for scenarios requiring maximum precision, particularly in';
                ' scientific analysis, microscopy, medical imaging, and technical applications';
                ' where specific reference features must be maintained at fixed positions.';
                '';
                ' ==============================================================================';
                ' CORE FEATURES';
                ' ==============================================================================';
                '';
                ' MANUAL ALIGNMENT (Start Button):';
                ' - User-guided point-by-point frame alignment';
                ' - Interactive zoom/pan for pixel-perfect point selection';
                ' - Visual feedback with target markers and displacement vectors';
                ' - Common target alignment principle (first frame sets target)';
                ' - Multiple frame range selection support';
                ' - Comprehensive displacement statistics and visualization';
                ' - Metadata export (MAT file with all alignment data)';
                '';
                ' ALIGNMENT QUALITY CHECKER (Check Alignment Button):';
                ' - Mean Absolute Difference (MAD) metric for alignment verification';
                ' - User-defined ROI on high-contrast background regions';
                ' - Frame-by-frame MAD calculation against reference frame';
                ' - Customizable threshold for misalignment detection';
                ' - Memory-efficient processing (2 frames loaded at a time)';
                ' - Comprehensive TXT report with all statistics';
                ' - Automatic plot generation and saving (PNG)';
                ' - Identifies specific frames requiring manual adjustment';
                '';
                ' INTEGRATION WITH ViFoSe:';
                ' - Automatically inherits FPS from VideoStructure if available';
                ' - Seamless workflow: ViFoSe → extract frames → STABM → stabilize';
                ' - Standalone operation also supported with manual FPS entry';
                '';
                ' ==============================================================================';
                ' MANUAL ALIGNMENT - ALGORITHMIC PRINCIPLE';
                ' ==============================================================================';
                '';
                ' The core concept is "common target alignment":';
                '';
                ' 1. USER SELECTS REFERENCE POINT IN FIRST FRAME';
                '    - This point becomes the COMMON TARGET for all subsequent frames';
                '    - Target coordinates: (X_target, Y_target)';
                '';
                ' 2. FOR EACH FOLLOWING FRAME:';
                '    - User selects the SAME PHYSICAL POINT (appears at different coordinates)';
                '    - Current point coordinates: (X_current, Y_current)';
                '';
                ' 3. TRANSLATION VECTOR CALCULATION:';
                '    - dx = X_target - X_current';
                '    - dy = Y_target - Y_current';
                '';
                ' 4. FRAME TRANSFORMATION:';
                '    - Apply pure translation using imtranslate([dx, dy])';
                '    - No rotation, scaling, or other transformations';
                '';
                ' 5. RESULT:';
                '    - All selected points now appear at identical (X_target, Y_target) coordinates';
                '    - Reference feature remains fixed throughout entire video sequence';
                '';
                ' ==============================================================================';
                ' ALIGNMENT CHECKER - ALGORITHMIC PRINCIPLE';
                ' ==============================================================================';
                '';
                ' The Mean Absolute Difference (MAD) metric quantifies frame misalignment:';
                '';
                ' 1. USER DEFINES ROI:';
                '    - Rectangular region on high-contrast background';
                '    - Should contain stable features with clear edges';
                '';
                ' 2. MAD CALCULATION FOR EACH FRAME:';
                '    - MAD = mean(|Reference_ROI(x,y) - Current_ROI(x,y)|)';
                '    - Averaged over all pixels in ROI';
                '    - Range: [0, 255] for 8-bit images';
                '';
                ' 3. INTERPRETATION:';
                '    - MAD ≈ 0: Perfect alignment (frames identical in ROI)';
                '    - MAD > threshold: Misalignment detected';
                '    - Larger MAD = greater misalignment';
                '';
                ' 4. OUTPUT:';
                '    - List of frames exceeding threshold (require manual alignment)';
                '    - Complete MAD table for all frames';
                '    - Statistical analysis (mean, median, std, max, min)';
                '';
                ' ==============================================================================';
                ' MANUAL ALIGNMENT - WORKFLOW';
                ' ==============================================================================';
                '';
                ' STEP 1 - Setup:';
                '   - Click "Select input folder" → choose PNG frame directory';
                '   - Click "Select output folder" → choose destination for stabilized video';
                '   - (Optional) Click "Set output fps" → adjust frame rate';
                '';
                ' STEP 2 - Frame Selection:';
                '   - Click "Start"';
                '   - Enter start and end frame indices';
                '   - Option to add multiple ranges (non-contiguous frame selection)';
                '   - Click "Done" when all ranges selected';
                '';
                ' STEP 3 - Point Selection (for each frame):';
                '   a. Frame displayed with zoom/pan enabled';
                '   b. Navigate to reference point location';
                '   c. Press ENTER when ready to select point';
                '   d. Click precisely on the SAME PHYSICAL POINT';
                '   e. Visual feedback displayed:';
                '      - Red cross: current selection';
                '      - Green cross: target position (frames 2+)';
                '      - Yellow line: translation vector';
                '      - Distance from target shown';
                '   f. Review selection (3-second pause)';
                '   g. Automatic advance to next frame';
                '';
                ' STEP 4 - Processing:';
                '   - Translation vectors calculated automatically';
                '   - Frames aligned to common target';
                '   - Video saved as [folder]_manual_aligned.mp4';
                '';
                ' STEP 5 - Review Results:';
                '   - Summary pop-up with statistics';
                '   - Displacement plots displayed';
                '   - MAT file saved with complete alignment data';
                '';
                ' ==============================================================================';
                ' ALIGNMENT CHECKER - WORKFLOW';
                ' ==============================================================================';
                '';
                ' STEP 1 - Setup:';
                '   - Click "Select input folder" → choose PNG frame directory';
                '   - Click "Select output folder" → choose destination for reports';
                '';
                ' STEP 2 - Parameters:';
                '   - Click "Check Alignment"';
                '   - Enter alignment threshold (0-255, default: 0)';
                '   - Enter reference frame index (default: 1)';
                '';
                ' STEP 3 - ROI Selection:';
                '   - Reference frame displayed';
                '   - Zoom/pan to find high-contrast background region';
                '   - Press ENTER when ready';
                '   - Draw rectangular ROI';
                '   - Double-click inside ROI to confirm';
                '';
                ' STEP 4 - Processing:';
                '   - MAD calculated for all frames vs reference';
                '   - Memory-efficient: only 2 frames in RAM at once';
                '   - Progress dialog shows current frame';
                '';
                ' STEP 5 - Results:';
                '   - TXT report: [folder]_alignment_threshold.txt';
                '   - PNG plot: [folder]_alignment_check_plot.png';
                '   - Summary pop-up with verdict and statistics';
                '';
                ' ==============================================================================';
                ' INPUT PARAMETERS';
                ' ==============================================================================';
                '';
                ' MANUAL ALIGNMENT:';
                ' - inputFolder: Directory containing PNG frame sequence (required)';
                ' - outputFolder: Destination for stabilized video (required)';
                ' - outputFPS: Desired frame rate (default: detected from ViFoSe or 30)';
                ' - frameRanges: Multiple start-end pairs for non-contiguous selection';
                '';
                ' ALIGNMENT CHECKER:';
                ' - inputFolder: Directory containing PNG frames (required)';
                ' - outputFolder: Destination for reports and plots (required)';
                ' - threshold: MAD threshold value (0-255, default: 0)';
                ' - referenceFrame: Frame index to use as reference (default: 1)';
                ' - ROI: User-drawn rectangular region for MAD calculation';
                '';
                ' ==============================================================================';
                ' OUTPUT FILES';
                ' ==============================================================================';
                '';
                ' MANUAL ALIGNMENT:';
                ' - Video: [folder]_manual_aligned.mp4';
                '   * MPEG-4 format, user-specified FPS';
                '   * All frames translated to align reference points';
                '';
                ' - Metadata: [folder]_manual_aligned_data.mat';
                '   * commonTarget: [X, Y] coordinates of target';
                '   * userPoints: [frame_idx, X, Y] for all user clicks';
                '   * displacements: [dx, dy] translation vectors';
                '   * selectedFrames: array of processed frame indices';
                '   * timestamp: processing date and time';
                '';
                ' - Plots: Interactive figure window (not saved)';
                '   * Horizontal displacement over frames';
                '   * Vertical displacement over frames';
                '   * Spatial distribution of user clicks';
                '';
                ' ALIGNMENT CHECKER:';
                ' - Report: [folder]_alignment_threshold.txt';
                '   * Header with parameters and metadata';
                '   * Statistics (max, min, mean, median, std MAD)';
                '   * Verdict (aligned or misaligned)';
                '   * List of frames above threshold';
                '   * Complete MAD table for all frames';
                '';
                ' - Plot: [folder]_alignment_check_plot.png';
                '   * MAD values over frame index';
                '   * MAD histogram distribution';
                '   * Threshold and mean MAD lines';
                '   * Misaligned frames highlighted';
                '';
                ' ==============================================================================';
                ' CRITICAL USER GUIDELINES';
                ' ==============================================================================';
                '';
                ' MANUAL ALIGNMENT:';
                ' 1. Choose a distinctive, stable reference point visible in ALL frames';
                ' 2. Use zoom and pan features to achieve pixel-level accuracy';
                ' 3. Select EXACTLY the same physical feature in every frame';
                ' 4. Consistency is paramount - small errors compound across frames';
                ' 5. Avoid points that move or change appearance between frames';
                ' 6. The green cross shows where your click SHOULD land (target)';
                ' 7. The yellow line visualizes the translation that will be applied';
                ' 8. Take your time - precision is more important than speed';
                '';
                ' ALIGNMENT CHECKER:';
                ' 1. Select ROI on high-contrast background region (not moving objects)';
                ' 2. Choose threshold based on expected alignment quality:';
                '    - Threshold = 0: Detects any pixel difference';
                '    - Threshold = 5-10: Tolerates minor alignment errors';
                '    - Threshold = 20+: Only detects major misalignments';
                ' 3. ROI should contain stable features with clear edges';
                ' 4. Avoid ROIs with moving content or illumination changes';
                ' 5. Larger ROI = more reliable statistics but slower processing';
                '';
                ' ==============================================================================';
                ' REQUIREMENTS';
                ' ==============================================================================';
                '';
                ' SOFTWARE:';
                ' - MATLAB R2020b or newer (recommended)';
                ' - Image Processing Toolbox (imtranslate, rgb2gray, imread, imwrite)';
                '';
                ' INPUT DATA:';
                ' - PNG frame sequence with sequential numbering';
                ' - Frames must have consistent dimensions';
                ' - Natural number ordering supported (frame1, frame2, ..., frame10)';
                '';
                ' HARDWARE:';
                ' - Sufficient RAM for frame loading (typical: 2-8 GB for 1080p sequences)';
                ' - Disk space for output video and reports';
                '';
                ' USER REQUIREMENTS:';
                ' - Patience and precision during manual point selection';
                ' - Understanding of reference point selection criteria';
                ' - Ability to identify same physical feature across frames';
                '';
                ' ==============================================================================';
                ' PERFORMANCE CHARACTERISTICS';
                ' ==============================================================================';
                '';
                ' MANUAL ALIGNMENT:';
                ' - Point Selection: ~30-60 seconds per frame (user-dependent)';
                ' - Translation Application: ~0.1 seconds per frame';
                ' - Video Writing: ~0.2 seconds per frame (1080p)';
                ' - Total Time: Dominated by user point selection phase';
                ' - Memory: O(N × frame_size) where N = total frames';
                '';
                ' ALIGNMENT CHECKER:';
                ' - MAD Calculation: ~0.05-0.2 seconds per frame';
                ' - Memory: O(2 × frame_size) - only reference + current frame';
                ' - Processing Speed: ~5-20 fps depending on resolution and ROI size';
                ' - Scalability: Handles any number of frames (memory-efficient)';
                '';
                ' ==============================================================================';
                ' ADVANTAGES AND LIMITATIONS';
                ' ==============================================================================';
                '';
                ' ADVANTAGES OVER AUTOMATIC METHODS:';
                ' 1. No dependence on texture or feature richness';
                ' 2. Works with low-contrast or homogeneous regions';
                ' 3. Immune to illumination changes between frames';
                ' 4. User maintains complete control over reference point';
                ' 5. Can align based on semantic understanding of scene';
                ' 6. Alignment checker validates results objectively';
                '';
                ' LIMITATIONS:';
                ' 1. Extremely time-consuming for long sequences';
                ' 2. Subject to human error and fatigue';
                ' 3. Corrects only global translations (no rotation/scaling)';
                ' 4. Requires visible reference point in all frames';
                ' 5. Quality depends entirely on user precision';
                '';
                ' ==============================================================================';
                ' TYPICAL APPLICATIONS';
                ' ==============================================================================';
                '';
                ' - Scientific video analysis with fixed reference markers';
                ' - Microscopy time-lapse sequences';
                ' - Medical imaging with anatomical landmarks';
                ' - Industrial inspection with fiducial markers';
                ' - Forensic video analysis requiring pixel-perfect alignment';
                ' - Quality control verification after automatic stabilization';
                ' - Research requiring quantitative alignment metrics';
                '';
                ' ==============================================================================';
                ' ERROR HANDLING AND VALIDATION';
                ' ==============================================================================';
                '';
                ' INPUT VALIDATION:';
                ' - Checks for missing input/output folders';
                ' - Validates FPS > 0';
                ' - Validates frame range within bounds';
                ' - Ensures all frames have user-selected points';
                ' - Checks threshold range (0-255)';
                ' - Validates reference frame index';
                '';
                ' RUNTIME CHECKS:';
                ' - Verifies clicks are within image boundaries';
                ' - Handles figure window closure gracefully';
                ' - Validates ROI size (minimum 5×5 pixels)';
                ' - Ensures grayscale conversion succeeds';
                '';
                ' USER FEEDBACK:';
                ' - Comprehensive error messages with specific issues';
                ' - Progress dialogs with detailed status';
                ' - Visual feedback during point selection';
                ' - Summary statistics after completion';
                '';
                ' ==============================================================================';
                ' TECHNICAL NOTES';
                ' ==============================================================================';
                '';
                ' MEMORY MANAGEMENT:';
                ' - Manual Alignment: All frames loaded into memory (lazy loading)';
                ' - Alignment Checker: Only 2 frames in memory at once';
                ' - Frame Data cleared after video writing';
                '';
                ' FILE NAMING:';
                ' - Output files prefixed with input folder name';
                ' - Prevents overwriting from different source folders';
                ' - Timestamp in metadata for traceability';
                '';
                ' COORDINATE SYSTEM:';
                ' - Origin: Top-left corner (1,1)';
                ' - X-axis: Horizontal (left to right)';
                ' - Y-axis: Vertical (top to bottom)';
                ' - Sub-pixel precision supported';
                '';
                ' TRANSFORMATION:';
                ' - Pure translation: T(x,y) = (x + dx, y + dy)';
                ' - Bilinear interpolation used by imtranslate';
                ' - Black padding for shifted regions';
                '';
                ' ==============================================================================';
                ' INTEGRATION WORKFLOW';
                ' ==============================================================================';
                '';
                ' RECOMMENDED PIPELINE:';
                '';
                ' 1. VIDEO EXTRACTION (ViFoSe):';
                '    - Extract frames from video';
                '    - Frames saved to Extracted_Frames/[videoName]/';
                '    - VideoStructure created with FPS and metadata';
                '';
                ' 2. INITIAL STABILIZATION (STABA - Optional):';
                '    - Try automatic stabilization first (SURF or Gradient)';
                '    - Check if results are satisfactory';
                '';
                ' 3. ALIGNMENT VERIFICATION (STABM - Check Alignment):';
                '    - Run alignment checker on STABA output or original frames';
                '    - Identify frames requiring manual correction';
                '    - Review MAD statistics and plots';
                '';
                ' 4. MANUAL CORRECTION (STABM - Manual Alignment):';
                '    - If misalignment detected, use manual alignment';
                '    - Select only problematic frame ranges (efficient)';
                '    - Achieve pixel-perfect alignment';
                '';
                ' 5. FINAL VERIFICATION (STABM - Check Alignment):';
                '    - Re-run alignment checker on manually aligned video';
                '    - Confirm all frames within threshold';
                '    - Export final stabilized sequence';
                '';
                ' ==============================================================================';
                ' WARNING';
                ' ==============================================================================';
                '';
                ' The quality of manual stabilization directly depends on the precision of';
                ' point selection. Inconsistent point selection will result in poor stabilization';
                ' and may make the output video worse than the original. Take time to be precise';
                ' and consistent! Use the alignment checker to verify results objectively.';
                '';
                ' ==============================================================================';
                };

            % Create a dialog box with the text
            fig = uifigure('Name', 'STABM Manual Stabilization Tool Info', 'Position', [100 100 800 650]);
            txt = uitextarea(fig, 'Value', infoText, 'Position', [20 20 760 580], ...
                'FontName', 'FixedWidth', 'Editable', 'off');
        end

        % Button pushed function: CheckAlignmentButton
        function CheckAlignmentButtonPushed(app, event)
            % This function calculates Mean Absolute Difference between a reference
            % frame and all other frames within a user-defined ROI to detect misalignment

            % ========================================================================
            % STEP 1: VALIDATE INPUT/OUTPUT FOLDERS
            % ========================================================================
            if isempty(app.inputFolder) || isempty(app.imageFiles)
                uialert(app.STABMUIFigure, 'Please select an input folder first.', 'Error');
                return;
            end

            if isempty(app.OutputFolder)
                uialert(app.STABMUIFigure, 'Please select an output folder first.', 'Error');
                return;
            end

            numFrames = length(app.imageFiles);

            % Get input folder base name for output file prefix
            [~, inputFolderName] = fileparts(app.inputFolder);

            % ========================================================================
            % STEP 2: ASK USER FOR THRESHOLD VALUE
            % ========================================================================
            prompt = {'Enter alignment threshold (0-255):'};
            dlgtitle = 'Set Alignment Threshold';
            dims = [1 60];
            definput = {'0'}; % Default threshold value = 0
            answer = inputdlg(prompt, dlgtitle, dims, definput);

            if isempty(answer)
                return; % User cancelled
            end

            threshold = str2double(answer{1});

            % Validate threshold
            if isnan(threshold) || threshold < 0 || threshold > 255
                uialert(app.STABMUIFigure, 'Threshold must be a number between 0 and 255.', 'Error');
                return;
            end

            % ========================================================================
            % STEP 3: ASK USER FOR REFERENCE FRAME INDEX
            % ========================================================================
            prompt = {sprintf('Enter reference frame index (1-%d):', numFrames)};
            dlgtitle = 'Select Reference Frame';
            dims = [1 60];
            definput = {'1'}; % Default to first frame
            answer = inputdlg(prompt, dlgtitle, dims, definput);

            if isempty(answer)
                return; % User cancelled
            end

            refFrameIdx = str2double(answer{1});

            % Validate reference frame index
            if isnan(refFrameIdx) || refFrameIdx < 1 || refFrameIdx > numFrames || ...
                    mod(refFrameIdx, 1) ~= 0
                uialert(app.STABMUIFigure, ...
                    sprintf('Reference frame must be an integer between 1 and %d.', numFrames), ...
                    'Error');
                return;
            end

            % ========================================================================
            % STEP 4: LOAD REFERENCE FRAME
            % ========================================================================
            refFramePath = fullfile(app.inputFolder, app.imageFiles(refFrameIdx).name);
            referenceFrame = imread(refFramePath);

            % Convert to grayscale if RGB
            if size(referenceFrame, 3) == 3
                referenceFrame = rgb2gray(referenceFrame);
            end

            % ========================================================================
            % STEP 5: INTERACTIVE ROI SELECTION WITH ZOOM/PAN
            % ========================================================================
            % Create figure for ROI selection
            figROI = figure('Name', sprintf('Reference Frame %d - Draw ROI', refFrameIdx), ...
                'NumberTitle', 'off', ...
                'Position', [100 100 900 700]);

            imshow(referenceFrame, []);
            title({sprintf('Reference Frame: %d of %d', refFrameIdx, numFrames), ...
                'ZOOM and PAN to find high-contrast background region', ...
                'Press ENTER when ready to draw ROI'}, ...
                'FontSize', 11, 'FontWeight', 'bold');

            % Enable zoom and pan
            zoom on;
            pan on;

            % Wait for user to press ENTER
            waitforbuttonpress;
            keyPressed = get(gcf, 'CurrentCharacter');
            while ~strcmp(keyPressed, char(13)) % Wait for ENTER key
                waitforbuttonpress;
                keyPressed = get(gcf, 'CurrentCharacter');
            end

            % Disable zoom/pan before ROI selection
            zoom off;
            pan off;

            % Update title for ROI drawing
            title({sprintf('Reference Frame: %d of %d', refFrameIdx, numFrames), ...
                'Draw a RECTANGULAR ROI on a high-contrast background area', ...
                'Double-click inside ROI when done'}, ...
                'FontSize', 11, 'FontWeight', 'bold', 'Color', 'red');

            % Draw rectangular ROI
            roi = drawrectangle('Color', 'yellow', 'LineWidth', 2);

            % Wait for user to finish (double-click)
            wait(roi);

            % Get ROI position [x, y, width, height]
            roiPosition = round(roi.Position);

            % Validate ROI
            if roiPosition(3) < 5 || roiPosition(4) < 5
                close(figROI);
                uialert(app.STABMUIFigure, 'ROI is too small. Please try again.', 'Error');
                return;
            end

            % Extract ROI bounds
            roiX = roiPosition(1);
            roiY = roiPosition(2);
            roiWidth = roiPosition(3);
            roiHeight = roiPosition(4);

            % Show ROI clearly
            hold on;
            rectangle('Position', roiPosition, 'EdgeColor', 'green', 'LineWidth', 3);
            title(sprintf('ROI Selected: [%d, %d, %d, %d]', roiX, roiY, roiWidth, roiHeight), ...
                'FontSize', 11, 'FontWeight', 'bold', 'Color', 'green');
            hold off;
            pause(2);
            close(figROI);

            % Extract reference ROI region
            refROI = referenceFrame(roiY:(roiY+roiHeight-1), roiX:(roiX+roiWidth-1));

            % ========================================================================
            % STEP 6: CALCULATE MAD FOR ALL FRAMES
            % ========================================================================
            % Create progress dialog
            progressDlg = uiprogressdlg(app.STABMUIFigure, 'Title', 'Checking Alignment', ...
                'Message', 'Calculating MAD values...', 'Indeterminate', 'off', 'Cancelable', 'off');

            madValues = zeros(numFrames, 1);
            framesAboveThreshold = [];

            % Convert reference ROI to double for calculations
            refROI_double = double(refROI);

            for i = 1:numFrames
                % Update progress
                progressDlg.Value = i / numFrames;
                progressDlg.Message = sprintf('Processing frame %d of %d...', i, numFrames);

                % MEMORY OPTIMIZATION: Load only current frame (not all frames)
                currentFramePath = fullfile(app.inputFolder, app.imageFiles(i).name);
                currentFrame = imread(currentFramePath);

                % Convert to grayscale if RGB
                if size(currentFrame, 3) == 3
                    currentFrame = rgb2gray(currentFrame);
                end

                % Extract same ROI region from current frame
                currentROI = currentFrame(roiY:(roiY+roiHeight-1), roiX:(roiX+roiWidth-1));
                currentROI_double = double(currentROI);

                % Calculate Mean Absolute Difference (MAD)
                % MAD = mean(|refFrame(x,y) - currentFrame(x,y)|) over all ROI pixels
                absoluteDiff = abs(refROI_double - currentROI_double);
                madValues(i) = mean(absoluteDiff(:));

                % Check if above threshold
                if madValues(i) > threshold
                    framesAboveThreshold = [framesAboveThreshold; i]; %#ok<AGROW>
                end

                % MEMORY CLEANUP: Clear current frame from memory
                clear currentFrame currentROI currentROI_double absoluteDiff;
            end

            % Close progress dialog
            close(progressDlg);

            % ========================================================================
            % STEP 7: CALCULATE STATISTICS
            % ========================================================================
            maxMAD = max(madValues);
            minMAD = min(madValues);
            meanMAD = mean(madValues);
            medianMAD = median(madValues);
            stdMAD = std(madValues);

            % Calculate percentage of frames above threshold
            percentAboveThreshold = (length(framesAboveThreshold) / numFrames) * 100;

            % ========================================================================
            % STEP 8: SAVE RESULTS TO TXT FILE (with input folder name prefix)
            % ========================================================================
            outputFileName = fullfile(app.OutputFolder, ...
                sprintf('%s_alignment_threshold.txt', inputFolderName));

            % Open file for writing
            fid = fopen(outputFileName, 'w');

            % Write header information
            fprintf(fid, '=================================================================\n');
            fprintf(fid, 'ALIGNMENT CHECK REPORT\n');
            fprintf(fid, '=================================================================\n');
            fprintf(fid, 'Date: %s\n', datetime('now'));
            fprintf(fid, 'Input folder: %s\n', app.inputFolder);
            fprintf(fid, 'Reference frame index: %d\n', refFrameIdx);
            fprintf(fid, 'ROI position [x, y, width, height]: [%d, %d, %d, %d]\n', ...
                roiX, roiY, roiWidth, roiHeight);
            fprintf(fid, 'ROI area (pixels): %d\n', roiWidth * roiHeight);
            fprintf(fid, 'Threshold: %.2f\n', threshold);
            fprintf(fid, 'Total frames analyzed: %d\n', numFrames);
            fprintf(fid, '=================================================================\n\n');

            % Write statistics (same as shown in pop-up)
            fprintf(fid, 'STATISTICS:\n');
            fprintf(fid, '-----------------------------------------------------------------\n');
            fprintf(fid, 'Frames above threshold: %d (%.2f%%)\n', ...
                length(framesAboveThreshold), percentAboveThreshold);
            fprintf(fid, 'Max MAD: %.2f\n', maxMAD);
            fprintf(fid, 'Min MAD: %.2f\n', minMAD);
            fprintf(fid, 'Mean MAD: %.2f\n', meanMAD);
            fprintf(fid, 'Median MAD: %.2f\n', medianMAD);
            fprintf(fid, 'Standard Deviation: %.2f\n', stdMAD);
            fprintf(fid, '\n');

            % Write verdict
            if isempty(framesAboveThreshold)
                fprintf(fid, 'VERDICT: ALIGNMENT OK - All frames within threshold\n');
            else
                fprintf(fid, 'VERDICT: MISALIGNMENT DETECTED - %d frames above threshold\n', ...
                    length(framesAboveThreshold));
            end
            fprintf(fid, '\n');

            % Write frames above threshold
            fprintf(fid, '=================================================================\n');
            fprintf(fid, 'FRAMES REQUIRING MANUAL ALIGNMENT (MAD > %.2f):\n', threshold);
            fprintf(fid, '=================================================================\n');
            if isempty(framesAboveThreshold)
                fprintf(fid, 'None - All frames are well aligned!\n');
            else
                fprintf(fid, 'Frame Index\tMAD Value\tDifference from Threshold\n');
                fprintf(fid, '-----------------------------------------------------------------\n');
                for i = 1:length(framesAboveThreshold)
                    frameIdx = framesAboveThreshold(i);
                    diff = madValues(frameIdx) - threshold;
                    fprintf(fid, '%d\t\t%.2f\t\t+%.2f\n', frameIdx, madValues(frameIdx), diff);
                end
            end
            fprintf(fid, '\n');

            % Write complete MAD table
            fprintf(fid, '=================================================================\n');
            fprintf(fid, 'COMPLETE MAD VALUES FOR ALL FRAMES:\n');
            fprintf(fid, '=================================================================\n');
            fprintf(fid, 'Frame Index\tMAD Value\tStatus\n');
            fprintf(fid, '-----------------------------------------------------------------\n');
            for i = 1:numFrames
                status = 'OK';
                if madValues(i) > threshold
                    status = 'MISALIGNED';
                end
                fprintf(fid, '%d\t\t%.2f\t\t%s\n', i, madValues(i), status);
            end

            fclose(fid);

            % ========================================================================
            % STEP 9: CREATE AND SAVE PLOTS (with input folder name prefix)
            % ========================================================================
            figPlot = figure('Name', 'Alignment Quality - MAD Analysis', ...
                'NumberTitle', 'off', 'Position', [100 100 1200 700], 'Visible', 'off');

            % Plot 1: MAD values over frames (WITHOUT frames above threshold line)
            subplot(2,1,1);
            plot(1:numFrames, madValues, 'b-', 'LineWidth', 1.5);
            hold on;
            plot([1, numFrames], [threshold, threshold], 'r--', 'LineWidth', 2);
            if ~isempty(framesAboveThreshold)
                plot(framesAboveThreshold, madValues(framesAboveThreshold), 'ro', ...
                    'MarkerSize', 8, 'LineWidth', 2);
            end
            plot([1, numFrames], [meanMAD, meanMAD], 'g--', 'LineWidth', 1.5);
            xlabel('Frame Index', 'FontSize', 11, 'FontWeight', 'bold');
            ylabel('MAD Value', 'FontSize', 11, 'FontWeight', 'bold');
            title(sprintf('Mean Absolute Difference (MAD) - Reference Frame: %d', refFrameIdx), ...
                'FontSize', 12, 'FontWeight', 'bold');

            % Legend without "Frames above threshold" entry
            if ~isempty(framesAboveThreshold)
                legend('MAD values', sprintf('Threshold (%.2f)', threshold), ...
                    sprintf('Misaligned frames (%d)', length(framesAboveThreshold)), ...
                    sprintf('Mean MAD (%.2f)', meanMAD), 'Location', 'best');
            else
                legend('MAD values', sprintf('Threshold (%.2f)', threshold), ...
                    sprintf('Mean MAD (%.2f)', meanMAD), 'Location', 'best');
            end
            grid on;
            hold off;

            % Plot 2: Histogram of MAD values
            subplot(2,1,2);
            histogram(madValues, 30, 'FaceColor', 'blue', 'EdgeColor', 'black');
            hold on;
            xline(threshold, 'r--', 'LineWidth', 2, 'Label', sprintf('Threshold: %.2f', threshold), ...
                'FontSize', 10, 'FontWeight', 'bold', 'LabelVerticalAlignment', 'bottom');
            xline(meanMAD, 'g--', 'LineWidth', 2, 'Label', sprintf('Mean: %.2f', meanMAD), ...
                'FontSize', 10, 'FontWeight', 'bold', 'LabelVerticalAlignment', 'top');
            xlabel('MAD Value', 'FontSize', 11, 'FontWeight', 'bold');
            ylabel('Frequency', 'FontSize', 11, 'FontWeight', 'bold');
            title('Distribution of MAD Values', 'FontSize', 12, 'FontWeight', 'bold');
            grid on;
            hold off;

            sgtitle(sprintf('Alignment Quality Analysis - %d/%d frames misaligned (%.2f%%)', ...
                length(framesAboveThreshold), numFrames, percentAboveThreshold), ...
                'FontSize', 14, 'FontWeight', 'bold');

            % Save figure as PNG (with input folder name prefix)
            plotFileName = fullfile(app.OutputFolder, ...
                sprintf('%s_alignment_check_plot.png', inputFolderName));
            saveas(figPlot, plotFileName);

            % Show the figure to the user
            set(figPlot, 'Visible', 'on');

            fprintf('Plot saved to: %s\n', plotFileName);

            % ========================================================================
            % STEP 10: DISPLAY RESULTS TO USER
            % ========================================================================
            % Prepare summary message
            if isempty(framesAboveThreshold)
                verdict = 'ALIGNMENT OK - All frames within threshold';
                verdictIcon = 'success';
            else
                verdict = sprintf('MISALIGNMENT DETECTED - %d frames above threshold', ...
                    length(framesAboveThreshold));
                verdictIcon = 'warning';
            end

            summaryMsg = sprintf(['ALIGNMENT CHECK COMPLETED\n\n' ...
                'Reference frame: %d\n' ...
                'ROI: [x=%d, y=%d, w=%d, h=%d]\n' ...
                'Threshold: %.2f\n\n' ...
                'STATISTICS:\n' ...
                '  Frames analyzed: %d\n' ...
                '  Frames above threshold: %d (%.2f%%)\n' ...
                '  Max MAD: %.2f\n' ...
                '  Min MAD: %.2f\n' ...
                '  Mean MAD: %.2f\n' ...
                '  Median MAD: %.2f\n' ...
                '  Standard Deviation: %.2f\n\n' ...
                'VERDICT:\n%s\n\n' ...
                'Results saved to:\n%s\n\n' ...
                'Plot saved to:\n%s'], ...
                refFrameIdx, roiX, roiY, roiWidth, roiHeight, threshold, ...
                numFrames, length(framesAboveThreshold), percentAboveThreshold, ...
                maxMAD, minMAD, meanMAD, medianMAD, stdMAD, ...
                verdict, outputFileName, plotFileName);

            uialert(app.STABMUIFigure, summaryMsg, 'Alignment Check Complete', 'Icon', verdictIcon);

            fprintf('Alignment check completed. Results saved to: %s\n', outputFileName);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.STABMUIFigure = uifigure('Visible', 'off');
            app.STABMUIFigure.Name = 'STABM';
            app.SelectoutputfolderButton = uibutton(app.STABMUIFigure, 'push');
            app.SelectoutputfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectoutputfolderButtonPushed, true);
            app.SelectoutputfolderButton.Text = 'Select output folder';
            app.SetoutputfpsButton = uibutton(app.STABMUIFigure, 'push');
            app.SetoutputfpsButton.ButtonPushedFcn = createCallbackFcn(app, @SetoutputfpsButtonPushed, true);
            app.SetoutputfpsButton.Text = 'Set output fps';
            app.FromEditField = uieditfield(app.STABMUIFigure, 'numeric');
            app.EditField = uieditfield(app.STABMUIFigure, 'text');
            app.EditField.Value = 'Suggested:';
            app.FromEditField_2 = uieditfield(app.STABMUIFigure, 'numeric');
            app.EditField_2 = uieditfield(app.STABMUIFigure, 'text');
            app.EditField_2.Value = 'Selected:';
            app.StartButton = uibutton(app.STABMUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SelectinputfolderButton = uibutton(app.STABMUIFigure, 'push');
            app.SelectinputfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectinputfolderButtonPushed, true);
            app.SelectinputfolderButton.Text = 'Select input folder';
            app.STABMButton = uibutton(app.STABMUIFigure, 'push');
            app.STABMButton.ButtonPushedFcn = createCallbackFcn(app, @STABMButtonPushed, true);
            app.STABMButton.Text = 'STABM';
            app.CheckAlignmentButton = uibutton(app.STABMUIFigure, 'push');
            app.CheckAlignmentButton.ButtonPushedFcn = createCallbackFcn(app, @CheckAlignmentButtonPushed, true);
            app.CheckAlignmentButton.Text = 'Check Alignment';
            % Show the figure after all components are created
            app.STABMUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = STABM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.STABMUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.STABMUIFigure)
        end
    end
end