classdef ViFoSe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VideoForegroundSegmentationViFoSeUIFigure  matlab.ui.Figure
        ChangeOutputColorButton         matlab.ui.control.Button
        LoadProjectButton               matlab.ui.control.Button
        SaveProjectButton               matlab.ui.control.Button
        LoadAutomaticBinaryMasksButton  matlab.ui.control.Button
        ManualAlignmentButton           matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        AutomaticSegmentationButton     matlab.ui.control.Button
        CropROIButton                   matlab.ui.control.Button
        AutomaticAlignmentButton        matlab.ui.control.Button
        LoadVideoButton                 matlab.ui.control.Button
        Frame00Slider                   matlab.ui.control.Slider
        Frame00SliderLabel              matlab.ui.control.Label
        ResetSegmentationButton         matlab.ui.control.Button
        SegmentForegroundButton         matlab.ui.control.Button
        SegmentBackgroundButton         matlab.ui.control.Button
        AboutUsButton                   matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    properties (Access = private)
        CurrentFrame         % Current frame as image
        ForegroundMaskTotal  % Cumulative mask for the foreground (3D array), contains ALL foreground masks of ALL frames
        BackgroundMaskTotal  % Cumulative mask for the background (3D array), contains ALL background masks of ALL frames
        ForegroundFrameIndices % Frame indices for each foreground mask, indicates which frame each mask belongs to (a frame can contain multiple masks)
        BackgroundFrameIndices % Frame indices for each background mask, indicates which frame each mask belongs to (a frame can contain multiple masks)
        BackgroundMaskTimestamps  % Timestamp for each background mask
        ForegroundMaskTimestamps  % Timestamp for each foreground mask
        ForegroundMaskHandle
        BackgroundMaskHandle
        CropRectROI    % Coordinates [x y w h] of the selected ROI
        ExtractedFrames      % Extracted frames (cell array)
        VideoStructure       % Complete video structure
        OutputFolder         % Output folder for frames
        FrameFilesList    % List of frame files in the output folder
        CurrentFrameIndex % Index of the current frame (based on saved files)
        OutputBackgroundColor   % Colore di sfondo per l'output video (RGB uint8, es. [0 0 0] per nero)

    end

    methods (Access = private)

        function loadFrameFromFolder(app, frameIndex)
            % Load a specific frame from the saved frames folder
            if frameIndex < 1 || frameIndex > length(app.FrameFilesList)
                return;
            end

            try
                frameFile = fullfile(app.OutputFolder, app.FrameFilesList{frameIndex});
                frame = imread(frameFile);
                app.CurrentFrame = frame;
                app.CurrentFrameIndex = frameIndex;

                % If there is a selected ROI, apply it
                if ~isempty(app.CropRectROI)
                    x = app.CropRectROI(1);
                    y = app.CropRectROI(2);
                    w = app.CropRectROI(3);
                    h = app.CropRectROI(4);
                    x = max(1, x); y = max(1, y);
                    w = min(size(frame,2)-x, w);
                    h = min(size(frame,1)-y, h);
                    frame = imcrop(frame, [x y w h]);
                end

                % Display in the three axes
                imshow(frame, 'Parent', app.UIAxes);

                % DISPLAY ROIS FOR THIS FRAME (BEFORE masks)
                displayROIsForCurrentFrame(app);

                % UIAxes2: use the new display function
                updateCombinedMaskDisplay(app);

                % UIAxes3: use the new display function
                updateUIAxes3Display(app);

                % Update slider label
                totalFrames = length(app.FrameFilesList);
                app.Frame00SliderLabel.Text = sprintf('Frame: %d/%d', frameIndex, totalFrames);

            catch ME
                uialert(app.VideoForegroundSegmentationViFoSeUIFigure, ...
                    sprintf('Error loading frame %d: %s', frameIndex, ME.message), ...
                    'Error');
            end
        end


        function updateCombinedMaskDisplay(app)
            if isempty(app.CurrentFrame)
                whiteImage = true(100, 100); % Binary instead of uint8
                imshow(whiteImage, 'Parent', app.UIAxes2);
                return;
            end

            % IF THERE IS A CROP, USE THE CROPPED FRAME DIMENSIONS
            if ~isempty(app.CropRectROI)
                % Create a temporary cropped frame to get the correct dimensions
                tempFrame = app.CurrentFrame;
                x = app.CropRectROI(1);
                y = app.CropRectROI(2);
                w = app.CropRectROI(3);
                h = app.CropRectROI(4);
                x = max(1, x); y = max(1, y);
                w = min(size(tempFrame,2)-x, w);
                h = min(size(tempFrame,1)-y, h);
                croppedFrame = imcrop(tempFrame, [x y w h]);
                frameSize = size(croppedFrame);
            else
                frameSize = size(app.CurrentFrame);
            end

            % INITIALIZE FINAL MASK (BLACK)
            finalMask = false(frameSize(1), frameSize(2)); % Binary
            currentFrameIdx = app.CurrentFrameIndex;

            % COLLECT ALL MASKS FOR THIS FRAME WITH THEIR TIMESTAMPS
            allMasks = [];
            allTypes = {};
            allTimestamps = [];

            % Background masks
            if ~isempty(app.BackgroundMaskTotal) && ~isempty(app.BackgroundFrameIndices)
                for i = 1:size(app.BackgroundMaskTotal, 3)
                    if app.BackgroundFrameIndices(i) == currentFrameIdx
                        resizedMask = imresize(app.BackgroundMaskTotal(:,:,i), [frameSize(1), frameSize(2)]);
                        % ENSURE THE MASK IS BINARY
                        resizedMask = logical(resizedMask);
                        allMasks = cat(3, allMasks, resizedMask);
                        allTypes{end+1} = 'background';
                        allTimestamps(end+1) = app.BackgroundMaskTimestamps(i);
                    end
                end
            end

            % Foreground masks
            if ~isempty(app.ForegroundMaskTotal) && ~isempty(app.ForegroundFrameIndices)
                for i = 1:size(app.ForegroundMaskTotal, 3)
                    if app.ForegroundFrameIndices(i) == currentFrameIdx
                        resizedMask = imresize(app.ForegroundMaskTotal(:,:,i), [frameSize(1), frameSize(2)]);
                        % ENSURE THE MASK IS BINARY
                        resizedMask = logical(resizedMask);
                        allMasks = cat(3, allMasks, resizedMask);
                        allTypes{end+1} = 'foreground';
                        allTimestamps(end+1) = app.ForegroundMaskTimestamps(i);
                    end
                end
            end

            % SORT AND APPLY IN TEMPORAL ORDER
            if ~isempty(allTimestamps)
                [~, sortOrder] = sort(allTimestamps); % From oldest to most recent

                for i = sortOrder
                    currentMask = allMasks(:,:,i);

                    % VERIFY THAT currentMask IS BINARY BEFORE USING IT AS INDEX
                    if ~islogical(currentMask)
                        currentMask = logical(currentMask);
                    end

                    if strcmp(allTypes{i}, 'background')
                        finalMask(currentMask) = false; % Black
                    else
                        finalMask(currentMask) = true;  % White
                    end
                end
            end
            % If there are no masks, everything remains black

            imshow(finalMask, 'Parent', app.UIAxes2);
        end

        function addBackgroundMask(app, newMask)
            currentTime = now; % Current timestamp

            if isempty(app.BackgroundMaskTotal)
                app.BackgroundMaskTotal = newMask;
                app.BackgroundFrameIndices = app.CurrentFrameIndex;
                app.BackgroundMaskTimestamps = currentTime;
            else
                app.BackgroundMaskTotal = cat(3, app.BackgroundMaskTotal, newMask);
                % Dimension 1: mask height (rows)
                % Dimension 2: mask width (columns)
                % Dimension 3: number of stored masks
                app.BackgroundFrameIndices = [app.BackgroundFrameIndices, app.CurrentFrameIndex];
                app.BackgroundMaskTimestamps = [app.BackgroundMaskTimestamps, currentTime];
            end
            updateCombinedMaskDisplay(app);
        end

        function addForegroundMask(app, newMask)

            currentTime = now; % Current timestamp

            if isempty(app.ForegroundMaskTotal)
                app.ForegroundMaskTotal = newMask;
                app.ForegroundFrameIndices = app.CurrentFrameIndex;
                app.ForegroundMaskTimestamps = currentTime;
            else
                app.ForegroundMaskTotal = cat(3, app.ForegroundMaskTotal, newMask);
                % Dimension 1: mask height (rows)
                % Dimension 2: mask width (columns)
                % Dimension 3: number of stored masks
                app.ForegroundFrameIndices = [app.ForegroundFrameIndices, app.CurrentFrameIndex];
                app.ForegroundMaskTimestamps = [app.ForegroundMaskTimestamps, currentTime];
            end
            updateCombinedMaskDisplay(app);
        end

        function addBackgroundMaskForFrame(app, newMask, frameIndex)

            currentTime = now; % Current timestamp

            if isempty(app.BackgroundMaskTotal)
                app.BackgroundMaskTotal = newMask;
                app.BackgroundFrameIndices = frameIndex;
                app.BackgroundMaskTimestamps = currentTime;
            else
                app.BackgroundMaskTotal = cat(3, app.BackgroundMaskTotal, newMask);
                app.BackgroundFrameIndices = [app.BackgroundFrameIndices, frameIndex];
                app.BackgroundMaskTimestamps = [app.BackgroundMaskTimestamps, currentTime];
            end
        end

        function addForegroundMaskForFrame(app, newMask, frameIndex)
            currentTime = now; % Current timestamp

            if isempty(app.ForegroundMaskTotal)
                app.ForegroundMaskTotal = newMask;
                app.ForegroundFrameIndices = frameIndex;
                app.ForegroundMaskTimestamps = currentTime;
            else
                app.ForegroundMaskTotal = cat(3, app.ForegroundMaskTotal, newMask);
                app.ForegroundFrameIndices = [app.ForegroundFrameIndices, frameIndex];
                app.ForegroundMaskTimestamps = [app.ForegroundMaskTimestamps, currentTime];
            end
        end

        function displayROIsForCurrentFrame(app)
            % CLEAN EXISTING ROIS FROM THE AXIS
            delete(findobj(app.UIAxes, 'Type', 'images.roi.Freehand'));
        end

        function updateUIAxes3Display(app)
            if isempty(app.CurrentFrame)
                return;
            end

            % Prepare frame for UIAxes3
            frame = app.CurrentFrame;

            % If there is a selected ROI, apply it
            if ~isempty(app.CropRectROI)
                x = app.CropRectROI(1);
                y = app.CropRectROI(2);
                w = app.CropRectROI(3);
                h = app.CropRectROI(4);
                x = max(1, x); y = max(1, y);
                w = min(size(frame,2)-x, w);
                h = min(size(frame,1)-y, h);
                frame = imcrop(frame, [x y w h]);
            end

            % INITIALIZE FINAL MASK (BLACK) - SAME LOGIC AS UIAxes2
            finalMask = false(size(frame, 1), size(frame, 2)); % BLACK
            currentFrameIdx = app.CurrentFrameIndex;

            % COLLECT ALL MASKS FOR THIS FRAME WITH THEIR TIMESTAMPS
            allMasks = [];
            allTypes = {};
            allTimestamps = [];

            % Background masks
            if ~isempty(app.BackgroundMaskTotal) && ~isempty(app.BackgroundFrameIndices)
                for i = 1:size(app.BackgroundMaskTotal, 3)
                    if app.BackgroundFrameIndices(i) == currentFrameIdx
                        resizedMask = imresize(app.BackgroundMaskTotal(:,:,i), [size(frame, 1), size(frame, 2)]);
                        resizedMask = logical(resizedMask);
                        allMasks = cat(3, allMasks, resizedMask);
                        allTypes{end+1} = 'background';
                        allTimestamps(end+1) = app.BackgroundMaskTimestamps(i);
                    end
                end
            end

            % Foreground masks
            if ~isempty(app.ForegroundMaskTotal) && ~isempty(app.ForegroundFrameIndices)
                for i = 1:size(app.ForegroundMaskTotal, 3)
                    if app.ForegroundFrameIndices(i) == currentFrameIdx
                        resizedMask = imresize(app.ForegroundMaskTotal(:,:,i), [size(frame, 1), size(frame, 2)]);
                        resizedMask = logical(resizedMask);
                        allMasks = cat(3, allMasks, resizedMask);
                        allTypes{end+1} = 'foreground';
                        allTimestamps(end+1) = app.ForegroundMaskTimestamps(i);
                    end
                end
            end

            % SORT AND APPLY IN TEMPORAL ORDER - SAME LOGIC AS UIAxes2
            if ~isempty(allTimestamps)
                [~, sortOrder] = sort(allTimestamps); % From oldest to most recent

                for i = sortOrder
                    currentMask = allMasks(:,:,i);

                    if ~islogical(currentMask)
                        currentMask = logical(currentMask);
                    end

                    if strcmp(allTypes{i}, 'background')
                        finalMask(currentMask) = false; % Black
                    else
                        finalMask(currentMask) = true;  % White
                    end
                end
            end

            % APPLY THE FINAL MASK TO THE FRAME WITH BACKGROUND COLOR
            bgColor = app.OutputBackgroundColor; % RGB triple, 0-255
            outputFrame = zeros(size(frame), 'uint8');
            for c = 1:3
                % Dove mask è true: mostra il frame originale
                % Dove mask è false: mostra il colore di sfondo
                outputFrame(:,:,c) = uint8(finalMask) .* frame(:,:,c) + ...
                                     uint8(~finalMask) * bgColor(c);
            end

            imshow(outputFrame, 'Parent', app.UIAxes3);
        end

        function resetAppState(app)
            % Reset all variables connected to previous video
            app.CurrentFrame = [];
            app.ForegroundMaskTotal = [];
            app.BackgroundMaskTotal = [];
            app.ForegroundFrameIndices = [];
            app.BackgroundFrameIndices = [];
            app.BackgroundMaskTimestamps = [];
            app.ForegroundMaskTimestamps = [];
            app.ForegroundMaskHandle = [];
            app.BackgroundMaskHandle = [];
            app.CropRectROI = [];  % <-- IMPORTANT ADDITION: reset ROI
            app.OutputBackgroundColor = [0 0 0]; % <-- NUOVO: colore di default nero
            app.ExtractedFrames = [];
            app.VideoStructure = [];
            app.OutputFolder = [];
            app.FrameFilesList = [];
            app.CurrentFrameIndex = 1;

            % Reset displays
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);

            % Reset slider and label
            app.Frame00Slider.Limits = [0, 5000];
            app.Frame00Slider.Value = 0;
            app.Frame00SliderLabel.Text = 'Frame: 0/0';

            % Disable buttons until new video is loaded
            app.SegmentForegroundButton.Enable = 'off';
            app.SegmentBackgroundButton.Enable = 'off';
            app.ResetSegmentationButton.Enable = 'off';
            app.CropROIButton.Enable = 'off';
            app.AutomaticSegmentationButton.Enable = 'off';
            app.AutomaticAlignmentButton.Enable = 'off';
            app.ManualAlignmentButton.Enable = 'off';
            app.Button.Enable = 'off';
            app.Button_2.Enable = 'off';
            app.Frame00Slider.Enable = 'off';
            app.LoadAutomaticBinaryMasksButton.Enable = 'off';
            app.SaveProjectButton.Enable = 'off';
            app.ChangeOutputColorButton.Enable = 'off'; % <-- NUOVO: disabilita il bottone colore
            % app.LoadProjectButton.Enable = 'off';
        end
        function binaryMask = createBinaryMaskForFrame(app, frameIndex, frameSize)
            % INITIALIZE FINAL MASK (BLACK)
            binaryMask = false(frameSize(1), frameSize(2));

            % COLLECT ALL MASKS FOR THIS FRAME WITH THEIR TIMESTAMPS
            allMasks = [];
            allTypes = {};
            allTimestamps = [];

            % Background masks
            if ~isempty(app.BackgroundMaskTotal) && ~isempty(app.BackgroundFrameIndices)
                for i = 1:size(app.BackgroundMaskTotal, 3)
                    if app.BackgroundFrameIndices(i) == frameIndex
                        resizedMask = imresize(app.BackgroundMaskTotal(:,:,i), [frameSize(1), frameSize(2)]);
                        resizedMask = logical(resizedMask);
                        allMasks = cat(3, allMasks, resizedMask);
                        allTypes{end+1} = 'background';
                        allTimestamps(end+1) = app.BackgroundMaskTimestamps(i);
                    end
                end
            end

            % Foreground masks
            if ~isempty(app.ForegroundMaskTotal) && ~isempty(app.ForegroundFrameIndices)
                for i = 1:size(app.ForegroundMaskTotal, 3)
                    if app.ForegroundFrameIndices(i) == frameIndex
                        resizedMask = imresize(app.ForegroundMaskTotal(:,:,i), [frameSize(1), frameSize(2)]);
                        resizedMask = logical(resizedMask);
                        allMasks = cat(3, allMasks, resizedMask);
                        allTypes{end+1} = 'foreground';
                        allTimestamps(end+1) = app.ForegroundMaskTimestamps(i);
                    end
                end
            end

            % SORT AND APPLY IN TEMPORAL ORDER
            if ~isempty(allTimestamps)
                [~, sortOrder] = sort(allTimestamps); % From oldest to most recent

                for i = sortOrder
                    currentMask = allMasks(:,:,i);

                    if ~islogical(currentMask)
                        currentMask = logical(currentMask);
                    end

                    if strcmp(allTypes{i}, 'background')
                        binaryMask(currentMask) = false; % Black
                    else
                        binaryMask(currentMask) = true;  % White
                    end
                end
            end
        end

        function outputFrame = createOutputFrameForFrame(app, frameIndex, originalFrame)
            % Create binary mask for this frame
            frameSize = size(originalFrame);
            binaryMask = app.createBinaryMaskForFrame(frameIndex, [frameSize(1), frameSize(2)]);

            % APPLY THE FINAL MASK TO THE FRAME WITH BACKGROUND COLOR
            bgColor = app.OutputBackgroundColor;
            outputFrame = zeros(size(originalFrame), 'uint8');
            for c = 1:3
                outputFrame(:,:,c) = uint8(binaryMask) .* originalFrame(:,:,c) + ...
                                     uint8(~binaryMask) * bgColor(c);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Frame00Slider
        function FrameSliderValueChanged(app, event)
            if isempty(app.FrameFilesList)
                return;
            end

            newFrameIndex = round(app.Frame00Slider.Value);
            app.loadFrameFromFolder(newFrameIndex);
        end

        % Button pushed function: CropROIButton
        function SelecrROICropROIButtonPushed(app, event)
            if isempty(app.CurrentFrame)
                uialert(app.VideoForegroundSegmentationViFoSeUIFigure, 'Load a video first.', 'Error');
                return;
            end

            % SAVE ORIGINAL MASKS BEFORE ANY CROP
            % Create copies of original masks from the complete original frame
            originalForegroundMasks = [];
            originalBackgroundMasks = [];
            originalForegroundIndices = [];
            originalBackgroundIndices = [];
            originalForegroundTimestamps = [];
            originalBackgroundTimestamps = [];

            % Load the complete original frame to get correct dimensions
            if ~isempty(app.FrameFilesList) && app.CurrentFrameIndex >= 1
                originalFrameFile = fullfile(app.OutputFolder, app.FrameFilesList{app.CurrentFrameIndex});
                originalFullFrame = imread(originalFrameFile);
                fullFrameSize = size(originalFullFrame);

                % Reconstruct original masks in complete dimensions
                if ~isempty(app.ForegroundMaskTotal) && ~isempty(app.ForegroundFrameIndices)
                    for i = 1:size(app.ForegroundMaskTotal, 3)
                        % Create empty mask of original dimensions
                        fullSizeMask = false(fullFrameSize(1), fullFrameSize(2));

                        % If there is already an applied ROI, expand the cropped mask
                        if ~isempty(app.CropRectROI)
                            x = app.CropRectROI(1);
                            y = app.CropRectROI(2);
                            w = app.CropRectROI(3);
                            h = app.CropRectROI(4);

                            % Ensure coordinates are valid
                            x = max(1, x); y = max(1, y);
                            w = min(size(fullSizeMask,2)-x+1, w);
                            h = min(size(fullSizeMask,1)-y+1, h);

                            % MODIFICATION: ENSURE DIMENSIONS ARE EQUAL
                            currentMask = app.ForegroundMaskTotal(:,:,i);
                            [maskH, maskW] = size(currentMask);

                            % Adjust dimensions to make them match
                            targetH = min(h, maskH);
                            targetW = min(w, maskW);

                            % Insert cropped mask in original position
                            if y+targetH-1 <= size(fullSizeMask,1) && x+targetW-1 <= size(fullSizeMask,2)
                                fullSizeMask(y:y+targetH-1, x:x+targetW-1) = currentMask(1:targetH, 1:targetW);
                            end
                        else
                            % If no ROI, use mask as is
                            fullSizeMask = app.ForegroundMaskTotal(:,:,i);
                        end

                        originalForegroundMasks = cat(3, originalForegroundMasks, fullSizeMask);
                        originalForegroundIndices = [originalForegroundIndices, app.ForegroundFrameIndices(i)];
                        originalForegroundTimestamps = [originalForegroundTimestamps, app.ForegroundMaskTimestamps(i)];
                    end
                end

                % Same for background masks
                if ~isempty(app.BackgroundMaskTotal) && ~isempty(app.BackgroundFrameIndices)
                    for i = 1:size(app.BackgroundMaskTotal, 3)
                        % Create empty mask of original dimensions
                        fullSizeMask = false(fullFrameSize(1), fullFrameSize(2));

                        % If there is already an applied ROI, expand the cropped mask
                        if ~isempty(app.CropRectROI)
                            x = app.CropRectROI(1);
                            y = app.CropRectROI(2);
                            w = app.CropRectROI(3);
                            h = app.CropRectROI(4);

                            % Ensure coordinates are valid
                            x = max(1, x); y = max(1, y);
                            w = min(size(fullSizeMask,2)-x+1, w);
                            h = min(size(fullSizeMask,1)-y+1, h);

                            % MODIFICATION: ENSURE DIMENSIONS ARE EQUAL
                            currentMask = app.BackgroundMaskTotal(:,:,i);
                            [maskH, maskW] = size(currentMask);

                            % Adjust dimensions to make them match
                            targetH = min(h, maskH);
                            targetW = min(w, maskW);

                            % Insert cropped mask in original position
                            if y+targetH-1 <= size(fullSizeMask,1) && x+targetW-1 <= size(fullSizeMask,2)
                                fullSizeMask(y:y+targetH-1, x:x+targetW-1) = currentMask(1:targetH, 1:targetW);
                            end
                        else
                            % If no ROI, use mask as is
                            fullSizeMask = app.BackgroundMaskTotal(:,:,i);
                        end

                        originalBackgroundMasks = cat(3, originalBackgroundMasks, fullSizeMask);
                        originalBackgroundIndices = [originalBackgroundIndices, app.BackgroundFrameIndices(i)];
                        originalBackgroundTimestamps = [originalBackgroundTimestamps, app.BackgroundMaskTimestamps(i)];
                    end
                end
            end

            % Select ROI
            if ~isempty(app.CropRectROI)
                x = app.CropRectROI(1);
                y = app.CropRectROI(2);
                w = app.CropRectROI(3);
                h = app.CropRectROI(4);
                x = max(1, x); y = max(1, y);
                w = min(size(app.CurrentFrame,2)-x, w);
                h = min(size(app.CurrentFrame,1)-y, h);
                currentDisplayFrame = imcrop(app.CurrentFrame, [x y w h]);
            else
                currentDisplayFrame = app.CurrentFrame;
            end

            imshow(currentDisplayFrame, 'Parent', app.UIAxes);
            h_roi = drawrectangle(app.UIAxes, 'Color', 'r');
            wait(h_roi);

            % Save new ROI
            if ~isempty(app.CropRectROI)
                newX = h_roi.Position(1) + app.CropRectROI(1) - 1;
                newY = h_roi.Position(2) + app.CropRectROI(2) - 1;
                newW = h_roi.Position(3);
                newH = h_roi.Position(4);
                app.CropRectROI = round([newX newY newW newH]);
            else
                app.CropRectROI = round(h_roi.Position);
            end

            % CREATE FOLDER WITH CROPPED FRAMES
            % Get video name from output folder instead of VideoStructure
            [~, videoName, ~] = fileparts(app.OutputFolder);
            croppedFolderName = [videoName '_cropped'];
            croppedFolderPath = fullfile('Extracted_Frames', croppedFolderName);

            % Delete existing folder if present
            if exist(croppedFolderPath, 'dir')
                rmdir(croppedFolderPath, 's');
            end

            % Create new folder
            if ~exist(croppedFolderPath, 'dir')
                mkdir(croppedFolderPath);
            end

            % Save all cropped frames with progress bar
            hWaitbar = waitbar(0, 'Saving cropped frames...', 'Name', 'Processing Crop');
            totalFrames = length(app.FrameFilesList);

            for frameIdx = 1:totalFrames
                waitbar(frameIdx/totalFrames, hWaitbar, sprintf('Processing frame %d of %d', frameIdx, totalFrames));

                % Load original frame
                frameFile = fullfile(app.OutputFolder, app.FrameFilesList{frameIdx});
                originalFrame = imread(frameFile);

                % Apply crop
                x = app.CropRectROI(1);
                y = app.CropRectROI(2);
                w = app.CropRectROI(3);
                h = app.CropRectROI(4);
                x = max(1, x); y = max(1, y);
                w = min(size(originalFrame,2)-x, w);
                h = min(size(originalFrame,1)-y, h);
                croppedFrame = imcrop(originalFrame, [x y w h]);

                % Save cropped frame
                outputFileName = sprintf('frame_%04d.png', frameIdx);
                outputFilePath = fullfile(croppedFolderPath, outputFileName);
                imwrite(croppedFrame, outputFilePath);
            end
            close(hWaitbar);

            % USE COMPLETE ORIGINAL MASKS TO APPLY NEW CROP
            % Reset masks
            app.ForegroundMaskTotal = [];
            app.BackgroundMaskTotal = [];
            app.ForegroundFrameIndices = [];
            app.BackgroundFrameIndices = [];
            app.ForegroundMaskTimestamps = [];
            app.BackgroundMaskTimestamps = [];

            % Recreate foreground masks with NEW crop applied to ORIGINAL masks
            if ~isempty(originalForegroundMasks)
                for i = 1:size(originalForegroundMasks, 3)
                    % Apply NEW crop to ORIGINAL mask
                    croppedMask = imcrop(originalForegroundMasks(:,:,i), app.CropRectROI);

                    % Add cropped mask
                    if isempty(app.ForegroundMaskTotal)
                        app.ForegroundMaskTotal = croppedMask;
                        app.ForegroundFrameIndices = originalForegroundIndices(i);
                        app.ForegroundMaskTimestamps = originalForegroundTimestamps(i);
                    else
                        app.ForegroundMaskTotal = cat(3, app.ForegroundMaskTotal, croppedMask);
                        app.ForegroundFrameIndices = [app.ForegroundFrameIndices, originalForegroundIndices(i)];
                        app.ForegroundMaskTimestamps = [app.ForegroundMaskTimestamps, originalForegroundTimestamps(i)];
                    end
                end
            end

            % Recreate background masks with NEW crop applied to ORIGINAL masks
            if ~isempty(originalBackgroundMasks)
                for i = 1:size(originalBackgroundMasks, 3)
                    % Apply NEW crop to ORIGINAL mask
                    croppedMask = imcrop(originalBackgroundMasks(:,:,i), app.CropRectROI);

                    % Add cropped mask
                    if isempty(app.BackgroundMaskTotal)
                        app.BackgroundMaskTotal = croppedMask;
                        app.BackgroundFrameIndices = originalBackgroundIndices(i);
                        app.BackgroundMaskTimestamps = originalBackgroundTimestamps(i);
                    else
                        app.BackgroundMaskTotal = cat(3, app.BackgroundMaskTotal, croppedMask);
                        app.BackgroundFrameIndices = [app.BackgroundFrameIndices, originalBackgroundIndices(i)];
                        app.BackgroundMaskTimestamps = [app.BackgroundMaskTimestamps, originalBackgroundTimestamps(i)];
                    end
                end
            end

            % The rest of the code remains the same...
            x = app.CropRectROI(1);
            y = app.CropRectROI(2);
            w = app.CropRectROI(3);
            h = app.CropRectROI(4);
            x = max(1, x); y = max(1, y);
            w = min(size(app.CurrentFrame,2)-x, w);
            h = min(size(app.CurrentFrame,1)-y, h);
            croppedFrame = imcrop(app.CurrentFrame, [x y w h]);

            imshow(croppedFrame, 'Parent', app.UIAxes);
            blackImage = false(size(croppedFrame, 1), size(croppedFrame, 2));
            imshow(blackImage, 'Parent', app.UIAxes2);
            updateUIAxes3Display(app);

            updateCombinedMaskDisplay(app);
            displayROIsForCurrentFrame(app);

            uialert(app.VideoForegroundSegmentationViFoSeUIFigure, ...
                sprintf('ROI selected. All existing masks have been cropped to the new region.\n\nCropped frames saved in: %s', croppedFolderPath), ...
                'ROI Cropped');
        end

        % Button pushed function: LoadVideoButton
        function LoadVideoButtonPushed(app, event)
            if isunix && ~ismac

                % RESET ALL VARIABLES AND PREVIOUS STATE
                app.resetAppState();

                [file, path] = uigetfile({'*.mp4;*.avi;*.mov;*.m4v;*.mkv', 'Video Files (*.mp4, *.avi, *.mov, *.m4v, *.mkv)'});
                if isequal(file, 0)
                    return;
                end
                fullPath = fullfile(path, file);

                try
                    % Create waitbar
                    h = waitbar(0, 'Preparing video extraction...', 'Name', 'Processing Video');

                    % Verify if ffmpeg is available
                    [status, ~] = system('which ffmpeg');
                    if status ~= 0
                        close(h);
                        uialert(app.VideoForegroundSegmentationViFoSeUIFigure, ...
                            'FFmpeg not installed. Install with: sudo apt install ffmpeg', ...
                            'FFmpeg Required');
                        return;
                    end

                    % Crea output folder
                    [~, videoName, ~] = fileparts(fullPath);
                    app.OutputFolder = fullfile('Extracted_Frames', videoName);

                    % Clean folder if already exists
                    if exist(app.OutputFolder, 'dir')
                        rmdir(app.OutputFolder, 's');
                    end
                    mkdir(app.OutputFolder);

                    % Extracting frames with FFmpeg
                    waitbar(0.1, h, 'Frames extraction with FFmpeg...');

                    outputPattern = fullfile(app.OutputFolder, 'frame_%04d.png');
                    ffmpegCmd = sprintf('ffmpeg -i "%s" -vsync 0 "%s"', fullPath, outputPattern);

                    [status, result] = system(ffmpegCmd);

                    if status ~= 0
                        close(h);
                        uialert(app.VideoForegroundSegmentationViFoSeUIFigure, ...
                            sprintf('Error FFmpeg: %s', result), ...
                            'Extraction Error');
                        return;
                    end

                    % Read extracted frames
                    waitbar(0.7, h, 'Loading frame...');

                    app.FrameFilesList = dir(fullfile(app.OutputFolder, 'frame_*.png'));
                    if isempty(app.FrameFilesList)
                        close(h);
                        uialert(app.VideoForegroundSegmentationViFoSeUIFigure, ...
                            'No fram extracted. The video could be corrupted o the codec is not supported.', ...
                            'No Frames Extracted');
                        return;
                    end

                    app.FrameFilesList = {app.FrameFilesList.name};
                    app.FrameFilesList = sort(app.FrameFilesList);

                    % Create a minimal videoInfo structure
                    app.VideoStructure = struct();
                    app.VideoStructure.videoName = videoName;
                    app.VideoStructure.videoPath = fullPath;
                    app.VideoStructure.frameRate = 30; % Default

                    % Initialize current frame
                    app.CurrentFrameIndex = 1;

                    % Slider configuration
                    totalFrames = length(app.FrameFilesList);
                    app.Frame00Slider.Limits = [1, totalFrames];
                    app.Frame00Slider.Value = 1;
                    app.Frame00SliderLabel.Text = sprintf('Frame: 1/%d', totalFrames);

                    % Load and show the first frame
                    waitbar(0.9, h, 'Loading first frame...');
                    firstFrameFile = fullfile(app.OutputFolder, app.FrameFilesList{1});
                    frame = imread(firstFrameFile);
                    app.CurrentFrame = frame;

                    % Show in the different axes
                    imshow(frame, 'Parent', app.UIAxes, 'InitialMagnification', 'fit');

                    % Set UIAxes2 to black
                    blackImage = false(size(frame, 1), size(frame, 2));
                    imshow(blackImage, 'Parent', app.UIAxes2);

                    % Set UIAxes3 with black mask
                    updateUIAxes3Display(app);

                    % Mantain dimensions
                    axis(app.UIAxes, 'image');
                    axis(app.UIAxes2, 'image');
                    axis(app.UIAxes3, 'image');

                    close(h);

                    % Enable the buttons
                    app.SegmentForegroundButton.Enable = 'on';
                    app.SegmentBackgroundButton.Enable = 'on';
                    app.ResetSegmentationButton.Enable = 'on';
                    app.CropROIButton.Enable = 'on';
                    app.AutomaticSegmentationButton.Enable = 'on';
                    app.AutomaticAlignmentButton.Enable = 'on';
                    app.ManualAlignmentButton.Enable = 'on';
                    app.Button.Enable = 'on';
                    app.Button_2.Enable = 'on';
                    app.Frame00Slider.Enable = 'on';
                    app.LoadAutomaticBinaryMasksButton.Enable ='on';
                    app.SaveProjectButton.Enable = 'on';
                    app.ChangeOutputColorButton.Enable = 'on';

                    uialert(app.VideoForegroundSegmentationViFoSeUIFigure, ...
                        sprintf('Video succesfully loaded!\n%d frames extraced in: %s', totalFrames, app.OutputFolder), ...
                        'Success');

                catch ME
                    if exist('h', 'var') && ishandle(h)
                        close(h);
                    end
                    uialert(app.VideoForegroundSegmentationViFoSeUIFigure, ...
                        sprintf('Loading errore: %s', ME.message), ...
                        'Error');
                end

            elseif ispc || ismac


                % RESET ALL VARIABLES AND PREVIOUS STATE
                app.resetAppState();
                [file, path] = uigetfile({'*.mp4;*.avi;*.mov', 'Video Files (*.mp4, *.avi, *.mov)'});
                if isequal(file, 0)
                    return;
                end
                fullPath = fullfile(path, file);

                % Extract frames using provided function
                try
                    % Create waitbar
                    h = waitbar(0, 'Extracting frames...', 'Name', 'Processing Video');
                    [app.ExtractedFrames, app.VideoStructure] = Grab_Video_Frames_par2(fullPath);

                    % Save output folder path
                    [~, videoName, ~] = fileparts(fullPath);
                    app.OutputFolder = fullfile('Extracted_Frames', videoName);
                    % Ensure folder exists
                    if ~exist(app.OutputFolder, 'dir')
                        mkdir(app.OutputFolder);
                    end

                    % Save each frame with progress
                    numFrames = length(app.ExtractedFrames);
                    for i = 1:numFrames
                        waitbar(i/numFrames, h, sprintf('Loading frame %d of %d...', i, numFrames));

                        filename = sprintf('frame_%04d.png', i);
                        fullFileName = fullfile(app.OutputFolder, filename);
                        imwrite(app.ExtractedFrames{i}, fullFileName);
                    end

                    % Get list of saved frame files
                    app.FrameFilesList = dir(fullfile(app.OutputFolder, 'frame_*.png'));
                    app.FrameFilesList = {app.FrameFilesList.name};
                    app.FrameFilesList = sort(app.FrameFilesList); % Sort by name

                    % Initialize current frame index
                    app.CurrentFrameIndex = 1;

                    % Configure slider
                    totalFrames = length(app.FrameFilesList);
                    app.Frame00Slider.Limits = [1, totalFrames];
                    app.Frame00Slider.Value = 1;
                    app.Frame00SliderLabel.Text = sprintf('Frame: 1/%d', totalFrames);

                    % Load and display first frame
                    firstFrameFile = fullfile(app.OutputFolder, app.FrameFilesList{1});
                    frame = imread(firstFrameFile);
                    app.CurrentFrame = frame;

                    % Set original image in various axes
                    imshow(frame, 'Parent', app.UIAxes, 'InitialMagnification', 'fit');

                    % Set UIAxes2 to all BLACK
                    blackImage = false(size(frame, 1), size(frame, 2)); % Binary mask all BLACK
                    imshow(blackImage, 'Parent', app.UIAxes2);

                    % Set UIAxes3 with black mask applied
                    updateUIAxes3Display(app); % This will apply black mask to frame


                    % Set axes size to maintain proportion
                    axis(app.UIAxes, 'image'); % Prevents aspect ratio from being altered
                    axis(app.UIAxes2, 'image');
                    axis(app.UIAxes3, 'image');

                    close(h); % Close waitbar

                    % Enable buttons and slider after loading
                    app.SegmentForegroundButton.Enable = 'on';
                    app.SegmentBackgroundButton.Enable = 'on';
                    app.ResetSegmentationButton.Enable = 'on';
                    app.CropROIButton.Enable = 'on';
                    app.AutomaticSegmentationButton.Enable = 'on';
                    app.AutomaticAlignmentButton.Enable = 'on';
                    app.ManualAlignmentButton.Enable = 'on';
                    app.Button.Enable = 'on';   % Prev frame
                    app.Button_2.Enable = 'on'; % Next frame
                    app.Frame00Slider.Enable = 'on';
                    app.Frame00Slider.Enable = 'on';
                    app.LoadAutomaticBinaryMasksButton.Enable ='on';
                    app.SaveProjectButton.Enable = 'on';
                    % app.LoadProjectButton.Enable='on';
                    app.ChangeOutputColorButton.Enable = 'on';

                    uialert(app.VideoForegroundSegmentationViFoSeUIFigure, ...
                        sprintf('Video succesfully loaded!\nFrame saved in: %s', app.OutputFolder), ...
                        'Success');

                catch ME
                    if exist('h', 'var') && ishandle(h)
                        close(h);
                    end
                    uialert(app.VideoForegroundSegmentationViFoSeUIFigure, ...
                        sprintf('Loading Error : %s', ME.message), ...
                        'Error');
                end

            else
                sprintf('Platform not supported');
            end
        end

        % Button pushed function: SegmentBackgroundButton
        function SegmentBackgroundButtonPushed(app, event)
            roi = drawfreehand(app.UIAxes);
            if isempty(roi)
                return;
            end

            % DISABLE INTERACTION AFTER SELECTION (WITH ERROR HANDLING)
            try
                roi.InteractionsAllowed = 'none';
            catch
                % If ROI is no longer valid, continue anyway
                warning('ROI object no longer valid for interaction control');
            end

            % SAVE ROI PROPERTIES TOGETHER WITH HANDLE
            roiData.handle = roi;
            roiData.position = roi.Position; % Save position
            roiData.color = 'r'; % Red for background
            roiData.type = 'background';
            roiData.frameIndex = app.CurrentFrameIndex; % Save source frame

            if isempty(app.BackgroundMaskHandle)
                app.BackgroundMaskHandle = roiData;
            else
                app.BackgroundMaskHandle(end+1) = roiData;
            end

            newMask = createMask(roi);

            totalFrames = length(app.FrameFilesList);
            currentFrame = app.CurrentFrameIndex;

            % Loop to request input until valid
            validInput = false;
            while ~validInput
                prompt = {'Insert start frame: ', 'Insert end frame: '};
                dlgTitle = 'Background Segmentation';
                dims = [1 60];
                defInput = {num2str(currentFrame), num2str(totalFrames)};
                answer = inputdlg(prompt, dlgTitle, dims, defInput);

                if isempty(answer)
                    return;
                end

                startFrame = str2double(answer{1});
                endFrame = str2double(answer{2});

                % Validity checks with specific messages
                if isnan(startFrame) || isnan(endFrame)
                    hWarn = warndlg('Please enter valid numbers for both start and end frames.', 'Invalid Input', 'modal');
                    uiwait(hWarn); % Wait for user to close message
                    continue; % This returns to start of loop
                end

                if startFrame < 1
                    hWarn = warndlg(sprintf('Start frame cannot be less than 1.'), 'Invalid Start Frame', 'modal');
                    uiwait(hWarn); % Wait for user to close message
                    continue; % This returns to start of loop
                end

                if endFrame > totalFrames
                    hWarn = warndlg(sprintf('End frame cannot be greater than %d.', totalFrames), 'Invalid End Frame', 'modal');
                    uiwait(hWarn); % Wait for user to close message
                    continue; % This returns to start of loop
                end

                if startFrame > endFrame
                    hWarn = warndlg('Start frame cannot be greater than end frame.', 'Invalid Frame Range', 'modal');
                    uiwait(hWarn); % Wait for user to close message
                    continue; % This returns to start of loop
                end

                validInput = true;
            end

            % ADD MASKS FOR ALL FRAMES IN SPECIFIED RANGE
            for frameIdx = startFrame:endFrame
                addBackgroundMaskForFrame(app, newMask, frameIdx);
            end

            % IMMEDIATELY UPDATE DISPLAYS
            updateCombinedMaskDisplay(app);
            updateUIAxes3Display(app);

            hMsg = msgbox(sprintf('Background segmentation completed for frames %d to %d.', startFrame, endFrame), 'Success!', 'modal');
            uiwait(hMsg); % Wait for user to close success message
        end

        % Button pushed function: SegmentForegroundButton
        function SegmentForegroundButtonPushed(app, event)
            roi = drawfreehand(app.UIAxes);
            if isempty(roi)
                return;
            end

            % DISABLE INTERACTION AFTER SELECTION (WITH ERROR HANDLING)
            try
                roi.InteractionsAllowed = 'none';
            catch
                % If ROI is no longer valid, continue anyway
                warning('ROI object no longer valid for interaction control');
            end

            % SAVE ROI PROPERTIES TOGETHER WITH HANDLE
            roiData.handle = roi;
            roiData.position = roi.Position; % Save position
            roiData.color = 'g'; % Green for foreground
            roiData.type = 'foreground';
            roiData.frameIndex = app.CurrentFrameIndex; % Save source frame

            if isempty(app.ForegroundMaskHandle)
                app.ForegroundMaskHandle = roiData;
            else
                app.ForegroundMaskHandle(end+1) = roiData;
            end

            newMask = createMask(roi);

            totalFrames = length(app.FrameFilesList);
            currentFrame = app.CurrentFrameIndex;

            % Loop to request input with specific error messages
            validInput = false;
            while ~validInput
                prompt = {'Insert start frame: ', 'Insert end frame: '};
                dlgTitle = 'Foreground Segmentation';
                dims = [1 60];
                defInput = {num2str(currentFrame), num2str(totalFrames)};
                answer = inputdlg(prompt, dlgTitle, dims, defInput);

                if isempty(answer)
                    return;
                end

                startFrame = str2double(answer{1});
                endFrame = str2double(answer{2});

                % Validity checks with specific messages
                if isnan(startFrame) || isnan(endFrame)
                    hWarn = warndlg('Please enter valid numbers for both start and end frames.', 'Invalid Input', 'modal');
                    uiwait(hWarn); % Wait for user to close message
                    continue; % This returns to start of loop
                end

                if startFrame < 1
                    hWarn = warndlg(sprintf('Start frame cannot be less than 1.'), 'Invalid Start Frame', 'modal');
                    uiwait(hWarn); % Wait for user to close message
                    continue; % This returns to start of loop
                end

                if endFrame > totalFrames
                    hWarn = warndlg(sprintf('End frame cannot be greater than %d.', totalFrames), 'Invalid End Frame', 'modal');
                    uiwait(hWarn); % Wait for user to close message
                    continue; % This returns to start of loop
                end

                if startFrame > endFrame
                    hWarn = warndlg('Start frame cannot be greater than end frame.', 'Invalid Frame Range', 'modal');
                    uiwait(hWarn); % Wait for user to close message
                    continue; % This returns to start of loop
                end

                validInput = true;
            end

            % ADD MASKS FOR ALL FRAMES IN SPECIFIED RANGE
            for frameIdx = startFrame:endFrame
                addForegroundMaskForFrame(app, newMask, frameIdx);
            end

            % IMMEDIATELY UPDATE DISPLAYS
            updateCombinedMaskDisplay(app);
            updateUIAxes3Display(app);

            hMsg = msgbox(sprintf('Foreground segmentation completed for frames %d to %d.', startFrame, endFrame), 'Success!', 'modal');
            uiwait(hMsg); % Wait for user to close success message
        end

        % Button pushed function: ResetSegmentationButton
        function ResetSegmentationButtonPushed(app, event)
            % Calculate total number of frames
            totalFrames = length(app.FrameFilesList);
            currentFrame = app.CurrentFrameIndex;

            % Loop to request input until valid
            validInput = false;
            while ~validInput
                % Ask for frame range
                prompt = {'Insert start frame: ', 'Insert end frame: '};
                dlgTitle = 'Reset Segmentation';
                dims = [1 60];
                defInput = {num2str(currentFrame), num2str(totalFrames)};
                answer = inputdlg(prompt, dlgTitle, dims, defInput);

                if isempty(answer)
                    return;
                end

                startFrame = str2double(answer{1});
                endFrame = str2double(answer{2});

                % Validity checks with specific messages
                if isnan(startFrame) || isnan(endFrame)
                    hWarn = warndlg('Please enter valid numbers for both start and end frames.', 'Invalid Input', 'modal');
                    uiwait(hWarn); % Wait for user to close message
                    continue;
                end

                if startFrame < 1
                    hWarn = warndlg(sprintf('Start frame cannot be less than 1.'), 'Invalid Start Frame', 'modal');
                    uiwait(hWarn); % Wait for user to close message
                    continue;
                end

                if endFrame > totalFrames
                    hWarn = warndlg(sprintf('End frame cannot be greater than %d.', totalFrames), 'Invalid End Frame', 'modal');
                    uiwait(hWarn); % Wait for user to close message
                    continue;
                end

                if startFrame > endFrame
                    hWarn = warndlg('Start frame cannot be greater than end frame.', 'Invalid Frame Range', 'modal');
                    uiwait(hWarn); % Wait for user to close message
                    continue;
                end

                validInput = true;
            end

            % Remove only masks that belong to frames in the range
            if ~isempty(app.ForegroundMaskTotal) && ~isempty(app.ForegroundFrameIndices)
                % Find masks that are NOT in the reset range
                keepMaskIndices = app.ForegroundFrameIndices < startFrame | app.ForegroundFrameIndices > endFrame;

                if any(keepMaskIndices)
                    app.ForegroundMaskTotal = app.ForegroundMaskTotal(:, :, keepMaskIndices);
                    app.ForegroundFrameIndices = app.ForegroundFrameIndices(keepMaskIndices);
                    app.ForegroundMaskTimestamps = app.ForegroundMaskTimestamps(keepMaskIndices);
                else
                    app.ForegroundMaskTotal = [];
                    app.ForegroundFrameIndices = [];
                    app.ForegroundMaskTimestamps = [];
                end
            end

            if ~isempty(app.BackgroundMaskTotal) && ~isempty(app.BackgroundFrameIndices)
                % Find masks that are NOT in the reset range
                keepMaskIndices = app.BackgroundFrameIndices < startFrame | app.BackgroundFrameIndices > endFrame;

                if any(keepMaskIndices)
                    app.BackgroundMaskTotal = app.BackgroundMaskTotal(:, :, keepMaskIndices);
                    app.BackgroundFrameIndices = app.BackgroundFrameIndices(keepMaskIndices);
                    app.BackgroundMaskTimestamps = app.BackgroundMaskTimestamps(keepMaskIndices);
                else
                    app.BackgroundMaskTotal = [];
                    app.BackgroundFrameIndices = [];
                    app.BackgroundMaskTimestamps = [];
                end
            end

            % REMOVE ROI HANDLES FOR RESET FRAMES
            if ~isempty(app.ForegroundMaskHandle)
                validHandles = true(length(app.ForegroundMaskHandle), 1);
                for i = 1:length(app.ForegroundMaskHandle)
                    if isfield(app.ForegroundMaskHandle(i), 'frameIndex') && ...
                            app.ForegroundMaskHandle(i).frameIndex >= startFrame && ...
                            app.ForegroundMaskHandle(i).frameIndex <= endFrame
                        validHandles(i) = false;
                    else
                        validHandles(i) = true;
                    end
                end
                app.ForegroundMaskHandle = app.ForegroundMaskHandle(validHandles);
            end

            if ~isempty(app.BackgroundMaskHandle)
                validHandles = true(length(app.BackgroundMaskHandle), 1);
                for i = 1:length(app.BackgroundMaskHandle)
                    if isfield(app.BackgroundMaskHandle(i), 'frameIndex') && ...
                            app.BackgroundMaskHandle(i).frameIndex >= startFrame && ...
                            app.BackgroundMaskHandle(i).frameIndex <= endFrame
                        validHandles(i) = false;
                    else
                        validHandles(i) = true;
                    end
                end
                app.BackgroundMaskHandle = app.BackgroundMaskHandle(validHandles);
            end

            % Update current frame display
            currentFrameIdx = app.CurrentFrameIndex;
            if currentFrameIdx >= startFrame && currentFrameIdx <= endFrame
                % If current frame is in reset range, reload it
                app.loadFrameFromFolder(currentFrameIdx);
            else
                % Otherwise, only update mask display
                updateCombinedMaskDisplay(app);
                displayROIsForCurrentFrame(app); % ALSO UPDATE ROIS
            end

            hMsg = msgbox(sprintf('Segmentation reset from frame %d to %d. Masks cleared from memory.', startFrame, endFrame), 'Reset Done!', 'modal');
            uiwait(hMsg); % Wait for user to close success message
        end

        % Button pushed function: AboutUsButton
        function AboutUsButtonPushed(app, event)
            % Create a new UI window
            f = uifigure('Name', 'About Us', 'Position', [100 100 500 400]);

            % Add logo image
            logo = uiimage(f);
            logo.ImageSource = 'ViFoSe_Logo.png';
            logo.Position = [200 280 100 100]; % Adjust position if necessary

            % License text (as single string for uilabel)
            licenseText = sprintf([ ...
                'Copyright (C) 2025\n' ...
                'Filippo Piccinini (E-mail: filippo.piccinini85@gmail.com)\n' ...
                'Luca Rinaldi (E-mail: luca.rinaldi03@oulook.it)\n' ...
                'Michele Sarneri (E-mail: michele.sarneri99@gmail.com)\n' ...
                'Abdelrahman Abdelaziz Mohamed (E-mail: abdu.abdelaziz46@gmail.com)\n' ...
                'University of Bologna, Italy.\n' ...
                'All rights reserved.\n\n' ...
                'Redistribution and use of the material, with or without modification, is provided\n' ...
                'for academic research purpose only.\n\n' ...
                'This program is free software; you can redistribute it and/or modify it\n' ...
                'under the terms of the GNU General Public License version 3 (or higher)\n' ...
                'as published by the Free Software Foundation. This program is\n' ...
                'distributed WITHOUT ANY WARRANTY; without even the implied warranty of\n' ...
                'MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See the GNU\n' ...
                'General Public License for more details.' ...
                ]);

            % Add text below logo using uilabel
            txtLabel = uilabel(f);
            txtLabel.Text = licenseText;
            txtLabel.Position = [25 20 450 250];
            txtLabel.FontSize = 11;
            txtLabel.HorizontalAlignment = 'left';
            txtLabel.VerticalAlignment = 'top';
            txtLabel.WordWrap = 'on';
        end

        % Button pushed function: Button
        function PrevFrameButtonPushed(app, event)
            if isempty(app.FrameFilesList)
                return;
            end

            if app.CurrentFrameIndex > 1
                app.Frame00Slider.Value = app.CurrentFrameIndex - 1;
                FrameSliderValueChanged(app, []);
            end
        end

        % Button pushed function: Button_2
        function NextFrameButtonPushed(app, event)
            if isempty(app.FrameFilesList)
                return;
            end

            totalFrames = length(app.FrameFilesList);
            if app.CurrentFrameIndex < totalFrames
                app.Frame00Slider.Value = app.CurrentFrameIndex + 1;
                FrameSliderValueChanged(app, []);
            end
        end

        % Button pushed function: AutomaticSegmentationButton
        function AutomaticSegmentationButtonPushed(app, event)
            % Enable buttons and slider after loading
            app.SegmentForegroundButton.Enable = 'off';
            app.SegmentBackgroundButton.Enable = 'off';
            app.ResetSegmentationButton.Enable = 'off';
            app.CropROIButton.Enable = 'off';
            app.AutomaticSegmentationButton.Enable = 'on';
            app.AutomaticAlignmentButton.Enable = 'off';
            app.ManualAlignmentButton.Enable = 'off';
            app.Button.Enable = 'off';   % Prev frame
            app.Button_2.Enable = 'off'; % Next frame
            app.Frame00Slider.Enable = 'off';
            app.Frame00Slider.Enable = 'off';
            app.LoadAutomaticBinaryMasksButton.Enable ='on';
            app.SaveProjectButton.Enable = 'on';
            app.LoadProjectButton.Enable ='on';
            SamApp = SAM();
        end

        % Button pushed function: AutomaticAlignmentButton
        function AutomaticAlignmentButtonPushed(app, event)
            % Enable buttons and slider after loading
            app.SegmentForegroundButton.Enable = 'off';
            app.SegmentBackgroundButton.Enable = 'off';
            app.ResetSegmentationButton.Enable = 'off';
            app.CropROIButton.Enable = 'off';
            app.AutomaticSegmentationButton.Enable = 'off';
            app.AutomaticAlignmentButton.Enable = 'on';
            app.ManualAlignmentButton.Enable = 'off';
            app.Button.Enable = 'off';   % Prev frame
            app.Button_2.Enable = 'off'; % Next frame
            app.Frame00Slider.Enable = 'off';
            app.Frame00Slider.Enable = 'off';
            app.LoadAutomaticBinaryMasksButton.Enable ='off';
            app.SaveProjectButton.Enable = 'on';
            app.LoadProjectButton.Enable ='on';


            % Save VideoStructure in base workspace before launching STABA
            VideoStructure = app.VideoStructure;
            assignin('base', 'VideoStructure', VideoStructure);

            % Now launch STABA
            StabaApp = STABA();
        end

        % Button pushed function: ManualAlignmentButton
        function ManualAlignmentButtonPushed(app, event)
            % Enable buttons and slider after loading
            app.SegmentForegroundButton.Enable = 'off';
            app.SegmentBackgroundButton.Enable = 'off';
            app.ResetSegmentationButton.Enable = 'off';
            app.CropROIButton.Enable = 'off';
            app.AutomaticSegmentationButton.Enable = 'off';
            app.AutomaticAlignmentButton.Enable = 'off';
            app.ManualAlignmentButton.Enable = 'on';
            app.Button.Enable = 'off';   % Prev frame
            app.Button_2.Enable = 'off'; % Next frame
            app.Frame00Slider.Enable = 'off';
            app.Frame00Slider.Enable = 'off';
            app.LoadAutomaticBinaryMasksButton.Enable ='off';
            app.SaveProjectButton.Enable = 'on';
            app.LoadProjectButton.Enable ='on';

            % Save VideoStructure in base workspace before launching STABM
            VideoStructure = app.VideoStructure;
            assignin('base', 'VideoStructure', VideoStructure);

            Stabmpp = STABM();
        end

        % Button pushed function: LoadAutomaticBinaryMasksButton
        function LoadAutomaticBinaryMasksButtonPushed(app, event)
            % Enable buttons and slider after loading
            app.SegmentForegroundButton.Enable = 'on';
            app.SegmentBackgroundButton.Enable = 'on';
            app.ResetSegmentationButton.Enable = 'on';
            app.CropROIButton.Enable = 'on';
            app.AutomaticSegmentationButton.Enable = 'on';
            app.AutomaticAlignmentButton.Enable = 'on';
            app.ManualAlignmentButton.Enable = 'on';
            app.Button.Enable = 'on';   % Prev frame
            app.Button_2.Enable = 'on'; % Next frame
            app.Frame00Slider.Enable = 'on';
            app.Frame00Slider.Enable = 'on';
            app.LoadAutomaticBinaryMasksButton.Enable ='on';
            app.SaveProjectButton.Enable = 'on';
            app.LoadProjectButton.Enable ='on';

            % Open dialog to select folder
            folderPath = uigetdir('', 'Select folder containing binary masks');

            if folderPath == 0
                return;
            end

            try
                % Search for image files in folder
                imageExtensions = {'*.png', '*.jpg', '*.jpeg', '*.bmp', '*.tif', '*.tiff'};
                fileList = [];
                for i = 1:length(imageExtensions)
                    files = dir(fullfile(folderPath, imageExtensions{i}));
                    fileList = [fileList; files];
                end

                if isempty(fileList)
                    uialert(app.VideoForegroundSegmentationViFoSeUIFigure, ...
                        'No image files found in the selected folder.', 'No Files Found');
                    return;
                end

                % Sort files by name
                [~, sortedIdx] = sort({fileList.name});
                fileList = fileList(sortedIdx);

                % CALCULATE EXPECTED MASK DIMENSIONS
                if ~isempty(app.CropRectROI)
                    % If there is a crop, use ROI dimensions
                    expectedHeight = app.CropRectROI(4);
                    expectedWidth = app.CropRectROI(3);
                else
                    % If no crop, use original frame dimensions
                    if ~isempty(app.CurrentFrame)
                        expectedHeight = size(app.CurrentFrame, 1);
                        expectedWidth = size(app.CurrentFrame, 2);
                    else
                        % If no current frame, load first frame to get dimensions
                        firstFrameFile = fullfile(app.OutputFolder, app.FrameFilesList{1});
                        firstFrame = imread(firstFrameFile);
                        expectedHeight = size(firstFrame, 1);
                        expectedWidth = size(firstFrame, 2);
                    end
                end

                % DIMENSION CHECK: verify first mask
                firstMaskFile = fullfile(folderPath, fileList(1).name);
                firstMask = imread(firstMaskFile);

                % Convert to binary mask if necessary
                if ~islogical(firstMask)
                    if size(firstMask, 3) == 3
                        firstMask = rgb2gray(firstMask);
                    end
                    firstMask = imbinarize(firstMask);
                end

                [maskHeight, maskWidth] = size(firstMask);

                % Compare dimensions
                if maskHeight ~= expectedHeight || maskWidth ~= expectedWidth
                    uialert(app.VideoForegroundSegmentationViFoSeUIFigure, ...
                        sprintf(['Mask dimensions do not match video dimensions!\n\n' ...
                        'Expected dimensions: %d x %d pixels\n' ...
                        'Mask dimensions: %d x %d pixels\n\n' ...
                        'Please check if the video has been cropped or load masks with the correct dimensions.'], ...
                        expectedHeight, expectedWidth, maskHeight, maskWidth), ...
                        'Dimension Mismatch Error');
                    return;
                end

                % Ask user for frame range
                totalFrames = length(app.FrameFilesList);
                prompt = {'Start frame for masks:', 'End frame for masks:'};
                dlgtitle = 'Frame Range';
                dims = [1 35];
                definput = {'1', num2str(min(totalFrames, length(fileList)))};
                answer = inputdlg(prompt, dlgtitle, dims, definput);

                if isempty(answer)
                    return;
                end

                startFrame = str2double(answer{1});
                endFrame = str2double(answer{2});

                if isnan(startFrame) || isnan(endFrame) || startFrame < 1 || endFrame > totalFrames
                    uialert(app.VideoForegroundSegmentationViFoSeUIFigure, ...
                        'Invalid frame range.', 'Error');
                    return;
                end

                % VERIFY THAT NUMBER OF MASKS IS SUFFICIENT
                availableMasks = length(fileList);
                requiredMasks = endFrame - startFrame + 1;

                if availableMasks < requiredMasks
                    choice = questdlg(sprintf(['Available masks (%d) are fewer than required (%d).\n\n' ...
                        'Do you want to continue loading the available masks?'], ...
                        availableMasks, requiredMasks), ...
                        'Insufficient Masks', 'Yes', 'No', 'No');
                    if strcmp(choice, 'No')
                        return;
                    end
                    % Update endFrame based on available masks
                    endFrame = startFrame + availableMasks - 1;
                end

                % Load masks for specified range
                for frameIdx = startFrame:endFrame
                    maskIdx = frameIdx - startFrame + 1;
                    if maskIdx <= length(fileList)
                        maskFileName = fileList(maskIdx).name;
                        maskFilePath = fullfile(folderPath, maskFileName);

                        % Load and process mask
                        mask = imread(maskFilePath);

                        if ~islogical(mask)
                            if size(mask, 3) == 3
                                mask = rgb2gray(mask);
                            end
                            mask = imbinarize(mask);
                        end

                        % VERIFY DIMENSIONS FOR EACH MASK (optional, for safety)
                        [currentMaskHeight, currentMaskWidth] = size(mask);
                        if currentMaskHeight ~= expectedHeight || currentMaskWidth ~= expectedWidth
                            uialert(app.VideoForegroundSegmentationViFoSeUIFigure, ...
                                sprintf(['Mask "%s" has incorrect dimensions!\n\n' ...
                                'Expected: %d x %d pixels\n' ...
                                'Found: %d x %d pixels\n\n' ...
                                'Loading interrupted.'], ...
                                maskFileName, expectedHeight, expectedWidth, ...
                                currentMaskHeight, currentMaskWidth), ...
                                'Dimension Error');
                            return;
                        end

                        % Add as foreground mask
                        app.addForegroundMaskForFrame(mask, frameIdx);
                    end
                end

                % Update displays
                updateCombinedMaskDisplay(app);
                updateUIAxes3Display(app);

                uialert(app.VideoForegroundSegmentationViFoSeUIFigure, ...
                    sprintf('Loaded %d binary masks for frames %d to %d', ...
                    min(length(fileList), endFrame-startFrame+1), startFrame, endFrame), ...
                    'Success');

            catch ME
                uialert(app.VideoForegroundSegmentationViFoSeUIFigure, ...
                    sprintf('Error loading masks: %s', ME.message), 'Error');
            end
        end

        % Button pushed function: SaveProjectButton
        function SaveProjectButtonPushed(app, event)
            % Verify that a video is loaded
            if isempty(app.FrameFilesList) || isempty(app.OutputFolder)
                uialert(app.VideoForegroundSegmentationViFoSeUIFigure, ...
                    'No video loaded. Please load a video first.', 'Error');
                return;
            end

            try
                % ASK USER TO CHOOSE DESTINATION FOLDER
                projectFolderPath = uigetdir('', 'Select folder to save project videos');

                if projectFolderPath == 0
                    % User canceled selection
                    return;
                end

                % Extract original video name from loaded video path
                if ~isempty(app.VideoStructure) && isfield(app.VideoStructure, 'videoName')
                    videoName = app.VideoStructure.videoName;
                else
                    % Fallback: use output folder name
                    [~, videoName, ~] = fileparts(app.OutputFolder);
                    % Remove any suffixes like '_cropped'
                    if endsWith(videoName, '_cropped')
                        videoName = strrep(videoName, '_cropped', '');
                    end
                end

                % GET FPS FROM ORIGINAL VIDEO
                if ~isempty(app.VideoStructure) && isfield(app.VideoStructure, 'frameRate')
                    frameRate = app.VideoStructure.frameRate;
                else
                    % If not available, try to get FPS from original video file
                    try
                        if isfield(app.VideoStructure, 'videoPath')
                            videoInfo = VideoReader(app.VideoStructure.videoPath);
                            frameRate = videoInfo.FrameRate;
                        else
                            frameRate = 30; % Fallback to 30 FPS
                            warning('Frame rate not available, using 30 FPS as default');
                        end
                    catch
                        frameRate = 30; % Fallback to 30 FPS in case of error
                        warning('Unable to get frame rate, using 30 FPS as default');
                    end
                end

                % Define complete paths for videos WITH CORRECT NAMES
                videoPaths = struct();
                videoPaths.preprocessed = fullfile(projectFolderPath, [videoName '_preprocessedVideo.avi']);
                videoPaths.binary = fullfile(projectFolderPath, [videoName '_binaryMask.avi']);
                videoPaths.output = fullfile(projectFolderPath, [videoName '_outputVideo.avi']);

                % CHECK IF FOLDER CONTAINS FILES
                folderContent = dir(projectFolderPath);
                % Remove '.' and '..' entries and folders
                folderContent = folderContent(~ismember({folderContent.name}, {'.', '..'}));
                folderContent = folderContent([folderContent.isdir] == 0);

                % If there are files in the folder, ask for confirmation to DELETE EVERYTHING
                if ~isempty(folderContent)
                    fileList = '';
                    for i = 1:min(length(folderContent), 10) % Show maximum 10 files
                        fileList = [fileList, '• ', folderContent(i).name, '\n'];
                    end
                    if length(folderContent) > 10
                        fileList = [fileList, '• ... and ', num2str(length(folderContent) - 10), ' more files\n'];
                    end

                    choice = questdlg(sprintf(['The selected folder contains files:\n\n%s\n\n' ...
                        'Do you want to DELETE ALL FILES in this folder and save the new videos?'], fileList), ...
                        'Folder Not Empty', 'Yes, Delete All and Save', 'No, Cancel', 'No, Cancel');

                    if ~strcmp(choice, 'Yes, Delete All and Save')
                        % User chose "No, Cancel" or closed the window
                        return; % COMPLETELY INTERRUPT OPERATION
                    end

                    % DELETE ALL FILES IN THE FOLDER
                    hWaitbar = waitbar(0, 'Deleting existing files...', 'Name', 'Cleaning Folder');

                    for i = 1:length(folderContent)
                        waitbar(i/length(folderContent), hWaitbar, ...
                            sprintf('Deleting file %d of %d', i, length(folderContent)));

                        filePath = fullfile(projectFolderPath, folderContent(i).name);
                        try
                            delete(filePath);
                        catch
                            warning('Could not delete file: %s', filePath);
                        end
                    end
                    close(hWaitbar);
                end

                % Create video writers WITH ORIGINAL FPS
                % Preprocessed video writer
                preprocessedWriter = VideoWriter(videoPaths.preprocessed, 'Motion JPEG AVI');
                preprocessedWriter.FrameRate = frameRate;
                open(preprocessedWriter);

                % Binary mask video writer
                binaryWriter = VideoWriter(videoPaths.binary, 'Motion JPEG AVI');
                binaryWriter.FrameRate = frameRate;
                open(binaryWriter);

                % Output video writer
                outputWriter = VideoWriter(videoPaths.output, 'Motion JPEG AVI');
                outputWriter.FrameRate = frameRate;
                open(outputWriter);

                % Progress bar
                hWaitbar = waitbar(0, 'Saving project videos...', 'Name', 'Saving Project');
                totalFrames = length(app.FrameFilesList);

                % Save each frame in the three videos
                for frameIdx = 1:totalFrames
                    waitbar(frameIdx/totalFrames, hWaitbar, ...
                        sprintf('Processing frame %d of %d', frameIdx, totalFrames));

                    % Load original frame
                    frameFile = fullfile(app.OutputFolder, app.FrameFilesList{frameIdx});
                    originalFrame = imread(frameFile);

                    % Apply crop if present
                    if ~isempty(app.CropRectROI)
                        x = app.CropRectROI(1);
                        y = app.CropRectROI(2);
                        w = app.CropRectROI(3);
                        h = app.CropRectROI(4);
                        x = max(1, x); y = max(1, y);
                        w = min(size(originalFrame,2)-x, w);
                        h = min(size(originalFrame,1)-y, h);
                        croppedFrame = imcrop(originalFrame, [x y w h]);
                    else
                        croppedFrame = originalFrame;
                    end

                    % 1. PREPROCESSED VIDEO (UIAxes)
                    writeVideo(preprocessedWriter, croppedFrame);

                    % 2. BINARY MASK VIDEO (UIAxes2)
                    binaryMask = createBinaryMaskForFrame(app, frameIdx, size(croppedFrame));
                    % Convert binary mask to RGB for video
                    binaryMaskRGB = uint8(cat(3, binaryMask * 255, binaryMask * 255, binaryMask * 255));
                    writeVideo(binaryWriter, binaryMaskRGB);

                    % 3. OUTPUT VIDEO (UIAxes3)
                    outputFrame = createOutputFrameForFrame(app, frameIdx, croppedFrame);
                    writeVideo(outputWriter, outputFrame);
                end

                % Close writers
                close(preprocessedWriter);
                close(binaryWriter);
                close(outputWriter);
                close(hWaitbar);

                % Success message with FPS information
                uialert(app.VideoForegroundSegmentationViFoSeUIFigure, ...
                    sprintf(['Project saved successfully!\n\n', ...
                    'Videos saved in: %s\n', ...
                    'Frame rate: %.2f FPS (same as original video)\n\n', ...
                    '• %s_preprocessedVideo.avi\n', ...
                    '• %s_binaryMask.avi\n', ...
                    '• %s_outputVideo.avi'], ...
                    projectFolderPath, frameRate, videoName, videoName, videoName), ...
                    'Project Saved');

            catch ME
                % Error handling
                if exist('hWaitbar', 'var') && ishandle(hWaitbar)
                    close(hWaitbar);
                end

                % Close writers in case of error
                if exist('preprocessedWriter', 'var')
                    close(preprocessedWriter);
                end
                if exist('binaryWriter', 'var')
                    close(binaryWriter);
                end
                if exist('outputWriter', 'var')
                    close(outputWriter);
                end

                uialert(app.VideoForegroundSegmentationViFoSeUIFigure, ...
                    sprintf('Error saving project: %s', ME.message), 'Error');
            end
        end

        % Button pushed function: LoadProjectButton
        function LoadProjectButtonPushed(app, event)
            % RESET CURRENT APP STATE
            app.resetAppState();

            % Open dialog to select project folder
            projectFolderPath = uigetdir('', 'Select project folder containing saved videos');

            if projectFolderPath == 0
                % User canceled selection
                return;
            end

            try
                % Verify folder contains project videos
                videoFiles = dir(fullfile(projectFolderPath, '*.avi'));
                if isempty(videoFiles)
                    uialert(app.VideoForegroundSegmentationViFoSeUIFigure, ...
                        'No project videos found in the selected folder.', 'No Project Files');
                    return;
                end

                % Extract video name from first found video file
                firstVideoName = videoFiles(1).name;
                if contains(firstVideoName, '_preprocessedVideo.avi')
                    videoName = strrep(firstVideoName, '_preprocessedVideo.avi', '');
                elseif contains(firstVideoName, '_binaryMask.avi')
                    videoName = strrep(firstVideoName, '_binaryMask.avi', '');
                elseif contains(firstVideoName, '_outputVideo.avi')
                    videoName = strrep(firstVideoName, '_outputVideo.avi', '');
                else
                    videoName = firstVideoName;
                end

                % SEARCH FOR CORRESPONDING FRAME FOLDER
                frameFoldersToCheck = {};

                % 1. Original frame folder
                originalFolder = fullfile('Extracted_Frames', videoName);
                if exist(originalFolder, 'dir')
                    frameFoldersToCheck{end+1} = originalFolder;
                end

                % 2. Cropped frame folder
                croppedFolder = fullfile('Extracted_Frames', [videoName '_cropped']);
                if exist(croppedFolder, 'dir')
                    frameFoldersToCheck{end+1} = croppedFolder;
                end

                % 3. Search for other possible folders with similar names
                allExtractedFolders = dir('Extracted_Frames');
                for i = 1:length(allExtractedFolders)
                    if allExtractedFolders(i).isdir && ~strcmp(allExtractedFolders(i).name, '.') && ~strcmp(allExtractedFolders(i).name, '..')
                        folderName = allExtractedFolders(i).name;
                        if contains(folderName, videoName)
                            folderPath = fullfile('Extracted_Frames', folderName);
                            if ~ismember(folderPath, frameFoldersToCheck)
                                frameFoldersToCheck{end+1} = folderPath;
                            end
                        end
                    end
                end

                % Read dimensions from preprocessed video
                preprocessedVideoPath = fullfile(projectFolderPath, [videoName '_preprocessedVideo.avi']);
                if ~exist(preprocessedVideoPath, 'file')
                    uialert(app.VideoForegroundSegmentationViFoSeUIFigure, ...
                        'Preprocessed video not found in project folder.', 'Missing Video File');
                    return;
                end

                % Get dimensions from video
                videoInfo = VideoReader(preprocessedVideoPath);
                firstVideoFrame = readFrame(videoInfo);
                videoDimensions = size(firstVideoFrame);
                videoHeight = videoDimensions(1);
                videoWidth = videoDimensions(2);

                % Compare with each candidate frame folder
                selectedFrameFolder = '';
                for i = 1:length(frameFoldersToCheck)
                    frameFolder = frameFoldersToCheck{i};

                    % Check if folder contains frames
                    frameFiles = dir(fullfile(frameFolder, 'frame_*.png'));
                    if isempty(frameFiles)
                        continue;
                    end

                    % Read first frame to verify dimensions
                    firstFrameFile = fullfile(frameFolder, frameFiles(1).name);
                    firstFrame = imread(firstFrameFile);
                    frameDimensions = size(firstFrame);
                    frameHeight = frameDimensions(1);
                    frameWidth = frameDimensions(2);

                    % Compare dimensions
                    if frameHeight == videoHeight && frameWidth == videoWidth
                        selectedFrameFolder = frameFolder;
                        break;
                    end
                end

                % IF NO MATCHING FOLDER FOUND, CREATE IT AUTOMATICALLY
                if isempty(selectedFrameFolder)
                    % CREATE NEW FRAME FOLDER
                    newFrameFolder = fullfile('Extracted_Frames', [videoName '_from_project']);

                    % Delete folder if it already exists
                    if exist(newFrameFolder, 'dir')
                        rmdir(newFrameFolder, 's');
                    end

                    % Create new folder
                    if ~exist(newFrameFolder, 'dir')
                        mkdir(newFrameFolder);
                    end

                    % Extract frames from preprocessed video
                    hWaitbar = waitbar(0, 'Extracting frames from preprocessed video...', 'Name', 'Creating Frame Folder');

                    videoInfo = VideoReader(preprocessedVideoPath);
                    frameCount = 1;

                    while hasFrame(videoInfo)
                        waitbar(frameCount/videoInfo.NumFrames, hWaitbar, ...
                            sprintf('Extracting frame %d of %d', frameCount, videoInfo.NumFrames));

                        % Read frame
                        frame = readFrame(videoInfo);

                        % Save frame
                        outputFileName = sprintf('frame_%04d.png', frameCount);
                        outputFilePath = fullfile(newFrameFolder, outputFileName);
                        imwrite(frame, outputFilePath);

                        frameCount = frameCount + 1;
                    end

                    close(hWaitbar);

                    selectedFrameFolder = newFrameFolder;

                    uialert(app.VideoForegroundSegmentationViFoSeUIFigure, ...
                        sprintf('Frame folder created automatically!\n\nExtracted %d frames from preprocessed video to:\n%s', ...
                        frameCount-1, newFrameFolder), 'Frame Folder Created');
                end

                % PROJECT LOADING - SET APP STATE
                app.OutputFolder = selectedFrameFolder;

                % Get frame files list
                app.FrameFilesList = dir(fullfile(app.OutputFolder, 'frame_*.png'));
                app.FrameFilesList = {app.FrameFilesList.name};
                app.FrameFilesList = sort(app.FrameFilesList);

                % Configure slider
                totalFrames = length(app.FrameFilesList);
                app.Frame00Slider.Limits = [1, totalFrames];
                app.Frame00Slider.Value = 1;
                app.Frame00SliderLabel.Text = sprintf('Frame: 1/%d', totalFrames);

                % Load and display first frame
                app.CurrentFrameIndex = 1;
                app.loadFrameFromFolder(1);

                % RECONSTRUCT MASKS FROM SAVED VIDEOS
                reconstructMasksFromSavedVideos(app, projectFolderPath, videoName);

                % Enable buttons after loading
                app.SegmentForegroundButton.Enable = 'on';
                app.SegmentBackgroundButton.Enable = 'on';
                app.ResetSegmentationButton.Enable = 'on';
                app.CropROIButton.Enable = 'on';
                app.AutomaticSegmentationButton.Enable = 'on';
                app.AutomaticAlignmentButton.Enable = 'on';
                app.ManualAlignmentButton.Enable = 'on';
                app.Button.Enable = 'on';
                app.Button_2.Enable = 'on';
                app.Frame00Slider.Enable = 'on';
                app.LoadAutomaticBinaryMasksButton.Enable = 'on';
                app.SaveProjectButton.Enable = 'on';
                app.ChangeOutputColorButton.Enable = 'on';

                uialert(app.VideoForegroundSegmentationViFoSeUIFigure, ...
                    sprintf('Project loaded successfully!\n\nVideo: %s\nFrame folder: %s', ...
                    videoName, selectedFrameFolder), 'Project Loaded');

            catch ME
                uialert(app.VideoForegroundSegmentationViFoSeUIFigure, ...
                    sprintf('Error loading project: %s', ME.message), 'Error');
            end
        end

        % Helper function to reconstruct masks from saved videos
        function reconstructMasksFromSavedVideos(app, projectFolderPath, videoName)
            try
                % Reset existing masks
                app.ForegroundMaskTotal = [];
                app.BackgroundMaskTotal = [];
                app.ForegroundFrameIndices = [];
                app.BackgroundFrameIndices = [];
                app.ForegroundMaskTimestamps = [];
                app.BackgroundMaskTimestamps = [];

                % Binary mask video path
                binaryVideoPath = fullfile(projectFolderPath, [videoName '_binaryMask.avi']);

                if ~exist(binaryVideoPath, 'file')
                    uialert(app.VideoForegroundSegmentationViFoSeUIFigure, ...
                        'Binary mask video not found. Masks cannot be reconstructed.', 'Warning', 'Icon', 'warning');
                    return;
                end

                % Read binary mask video
                binaryVideo = VideoReader(binaryVideoPath);
                frameIdx = 1;

                hWaitbar = waitbar(0, 'Reconstructing masks from saved project...', 'Name', 'Loading Masks');

                while hasFrame(binaryVideo)
                    waitbar(frameIdx/binaryVideo.NumFrames, hWaitbar, ...
                        sprintf('Processing mask %d of %d', frameIdx, binaryVideo.NumFrames));

                    % Read binary mask frame
                    maskFrame = readFrame(binaryVideo);

                    % Convert to binary mask
                    if size(maskFrame, 3) == 3
                        maskGray = rgb2gray(maskFrame);
                    else
                        maskGray = maskFrame;
                    end

                    binaryMask = maskGray > 128; % Threshold to get binary mask

                    % Add mask as foreground (since mask video shows foreground in white and background in black)
                    if any(binaryMask(:)) % Only if there's something in the mask
                        app.addForegroundMaskForFrame(binaryMask, frameIdx);
                    end

                    frameIdx = frameIdx + 1;
                end

                close(hWaitbar);

                % Update displays
                updateCombinedMaskDisplay(app);
                updateUIAxes3Display(app);

            catch ME
                if exist('hWaitbar', 'var') && ishandle(hWaitbar)
                    close(hWaitbar);
                end
                fprintf('Warning: Could not reconstruct masks from saved project: %s\n', ME.message);
            end
        end

        % Button pushed function: ChangeOutputColorButton
        function ChangeOutputColorButtonPushed(app, event)
            % Define available colors
    colorNames = {'Black', 'White', 'Blue', 'Green', 'Red'};
    colorValues = {[0 0 0], [255 255 255], [0 0 255], [0 255 0], [255 0 0]};
    
    % Show selection dialog
    [idx, ok] = listdlg('PromptString', 'Select background color for output video:', ...
                         'SelectionMode', 'single', ...
                         'ListString', colorNames);
    if ok
        app.OutputBackgroundColor = colorValues{idx};
        % Immediately update the display
        updateUIAxes3Display(app);
    end
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VideoForegroundSegmentationViFoSeUIFigure = uifigure('Visible', 'off');
            app.VideoForegroundSegmentationViFoSeUIFigure.Name = 'Video Foreground Segmentation (ViFoSe)';
            app.UIAxes = uiaxes(app.VideoForegroundSegmentationViFoSeUIFigure);
            title(app.UIAxes, 'Preprocessed Video')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.Tag = 'UIAxes';
            app.UIAxes2 = uiaxes(app.VideoForegroundSegmentationViFoSeUIFigure);
            title(app.UIAxes2, 'Binary Masks')
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTick = [];
            app.UIAxes2.YTickLabel = {''; ''};
            app.UIAxes2.Tag = 'UIAxes2';
            app.UIAxes3 = uiaxes(app.VideoForegroundSegmentationViFoSeUIFigure);
            title(app.UIAxes3, 'Output Video')
            app.UIAxes3.XTick = [];
            app.UIAxes3.XTickLabel = '';
            app.UIAxes3.YTick = [];
            app.UIAxes3.YTickLabel = '';
            app.AboutUsButton = uibutton(app.VideoForegroundSegmentationViFoSeUIFigure, 'push');
            app.AboutUsButton.ButtonPushedFcn = createCallbackFcn(app, @AboutUsButtonPushed, true);
            app.AboutUsButton.Tag = 'AboutUsButton';
            app.AboutUsButton.Text = 'About Us';
            app.SegmentBackgroundButton = uibutton(app.VideoForegroundSegmentationViFoSeUIFigure, 'push');
            app.SegmentBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentBackgroundButtonPushed, true);
            app.SegmentBackgroundButton.Tag = 'SegmentBackgroundButton';
            app.SegmentBackgroundButton.Enable = 'off';
            app.SegmentBackgroundButton.Text = {'Segment'; 'Background'};
            app.SegmentForegroundButton = uibutton(app.VideoForegroundSegmentationViFoSeUIFigure, 'push');
            app.SegmentForegroundButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentForegroundButtonPushed, true);
            app.SegmentForegroundButton.Tag = 'SegmentForegroundButton';
            app.SegmentForegroundButton.Enable = 'off';
            app.SegmentForegroundButton.Text = {'Segment'; 'Foreground'};
            app.ResetSegmentationButton = uibutton(app.VideoForegroundSegmentationViFoSeUIFigure, 'push');
            app.ResetSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSegmentationButtonPushed, true);
            app.ResetSegmentationButton.Tag = 'ResetSegmentationButton';
            app.ResetSegmentationButton.Enable = 'off';
            app.ResetSegmentationButton.Text = {'Reset'; 'Segmentation'};
            app.Frame00SliderLabel = uilabel(app.VideoForegroundSegmentationViFoSeUIFigure);
            app.Frame00SliderLabel.Text = 'Frame: 0/0';
            app.Frame00Slider = uislider(app.VideoForegroundSegmentationViFoSeUIFigure);
            app.Frame00Slider.Limits = [0 5000];
            app.Frame00Slider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.Frame00Slider.Tag = 'FrameLabel';
            app.Frame00Slider.Enable = 'off';
            app.LoadVideoButton = uibutton(app.VideoForegroundSegmentationViFoSeUIFigure, 'push');
            app.LoadVideoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVideoButtonPushed, true);
            app.LoadVideoButton.Tag = 'LoadVideoButton';
            app.LoadVideoButton.Text = {'Load'; 'Video'};
            app.AutomaticAlignmentButton = uibutton(app.VideoForegroundSegmentationViFoSeUIFigure, 'push');
            app.AutomaticAlignmentButton.ButtonPushedFcn = createCallbackFcn(app, @AutomaticAlignmentButtonPushed, true);
            app.AutomaticAlignmentButton.Tag = 'AlignFramesButton';
            app.AutomaticAlignmentButton.Enable = 'off';
            app.AutomaticAlignmentButton.Text = {'Automatic'; 'Alignment'};
            app.CropROIButton = uibutton(app.VideoForegroundSegmentationViFoSeUIFigure, 'push');
            app.CropROIButton.ButtonPushedFcn = createCallbackFcn(app, @SelecrROICropROIButtonPushed, true);
            app.CropROIButton.Tag = 'CropROIButton';
            app.CropROIButton.Enable = 'off';
            app.CropROIButton.Text = {'Crop'; 'ROI'};
            app.AutomaticSegmentationButton = uibutton(app.VideoForegroundSegmentationViFoSeUIFigure, 'push');
            app.AutomaticSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @AutomaticSegmentationButtonPushed, true);
            app.AutomaticSegmentationButton.Tag = 'AutomaticSegmentationButton';
            app.AutomaticSegmentationButton.Enable = 'off';
            app.AutomaticSegmentationButton.Text = {'Automatic'; 'Segmentation'; ' '};
            app.Button = uibutton(app.VideoForegroundSegmentationViFoSeUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @PrevFrameButtonPushed, true);
            app.Button.Tag = 'PrevFrameButton';
            app.Button.Enable = 'off';
            app.Button.Text = '-';
            app.Button_2 = uibutton(app.VideoForegroundSegmentationViFoSeUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @NextFrameButtonPushed, true);
            app.Button_2.Tag = 'NextFrameButton';
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '+';
            app.ManualAlignmentButton = uibutton(app.VideoForegroundSegmentationViFoSeUIFigure, 'push');
            app.ManualAlignmentButton.ButtonPushedFcn = createCallbackFcn(app, @ManualAlignmentButtonPushed, true);
            app.ManualAlignmentButton.Enable = 'off';
            app.ManualAlignmentButton.Text = {'Manual'; 'Alignment'};
            app.LoadAutomaticBinaryMasksButton = uibutton(app.VideoForegroundSegmentationViFoSeUIFigure, 'push');
            app.LoadAutomaticBinaryMasksButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAutomaticBinaryMasksButtonPushed, true);
            app.LoadAutomaticBinaryMasksButton.Enable = 'off';
            app.LoadAutomaticBinaryMasksButton.Text = {'Load Automatic'; 'Binary Masks'};
            app.SaveProjectButton = uibutton(app.VideoForegroundSegmentationViFoSeUIFigure, 'push');
            app.SaveProjectButton.ButtonPushedFcn = createCallbackFcn(app, @SaveProjectButtonPushed, true);
            app.SaveProjectButton.Enable = 'off';
            app.SaveProjectButton.Text = {'Save '; 'Project'};
            app.LoadProjectButton = uibutton(app.VideoForegroundSegmentationViFoSeUIFigure, 'push');
            app.LoadProjectButton.ButtonPushedFcn = createCallbackFcn(app, @LoadProjectButtonPushed, true);
            app.LoadProjectButton.Text = {'Load'; 'Project'};
            app.ChangeOutputColorButton = uibutton(app.VideoForegroundSegmentationViFoSeUIFigure, 'push');
            app.ChangeOutputColorButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeOutputColorButtonPushed, true);
            app.ChangeOutputColorButton.Tag = 'ChangeOutputColorButton';
            app.ChangeOutputColorButton.Enable = 'off';
            app.ChangeOutputColorButton.Text = {'Change '; 'Output Color'};
            % Show the figure after all components are created
            app.VideoForegroundSegmentationViFoSeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ViFoSe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VideoForegroundSegmentationViFoSeUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VideoForegroundSegmentationViFoSeUIFigure)
        end
    end
end